classdef ISpin_SAGA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RecordingSettingsPanel          matlab.ui.container.Panel
        ForcechDropDown                 matlab.ui.control.DropDown
        ForcechDropDownLabel            matlab.ui.control.Label
        DockcomDropDown                 matlab.ui.control.DropDown
        DockcomDropDownLabel            matlab.ui.control.Label
        RefreshrateDropDown             matlab.ui.control.DropDown
        RefreshrateDropDownLabel        matlab.ui.control.Label
        ChpergridDropDown               matlab.ui.control.DropDown
        ChpergridDropDownLabel          matlab.ui.control.Label
        StartVisualizationChecknoiseButton  matlab.ui.control.Button
        SelectfolderButton              matlab.ui.control.Button
        EditField_saving                matlab.ui.control.EditField
        SavingsettingsLabel             matlab.ui.control.Label
        StartVisualizationCheckEMGButton  matlab.ui.control.Button
        FrequencyDropDown               matlab.ui.control.DropDown
        FrequencyDropDownLabel          matlab.ui.control.Label
        MusclenamesLabel                matlab.ui.control.Label
        ChannelsLabel                   matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        EditField_1                     matlab.ui.control.EditField
        Grid2CheckBox                   matlab.ui.control.CheckBox
        Grid1CheckBox                   matlab.ui.control.CheckBox
        TrainingSettingsPanel           matlab.ui.container.Panel
        COVthresholdEditField           matlab.ui.control.NumericEditField
        COVthresholdEditFieldLabel      matlab.ui.control.Label
        SILthresholdEditField           matlab.ui.control.NumericEditField
        SILthresholdEditFieldLabel      matlab.ui.control.Label
        EditField_MVC                   matlab.ui.control.EditField
        StartButton                     matlab.ui.control.Button
        NbIterationsEditField           matlab.ui.control.NumericEditField
        NbIterationsEditFieldLabel      matlab.ui.control.Label
        TrainingparametersLabel         matlab.ui.control.Label
        DurationtrainingEditField       matlab.ui.control.NumericEditField
        DurationtrainingEditFieldLabel  matlab.ui.control.Label
        MVCTargetEditField              matlab.ui.control.NumericEditField
        MVCTargetEditFieldLabel         matlab.ui.control.Label
        Gauge                           matlab.ui.control.SemicircularGauge
        StartMVCButton                  matlab.ui.control.Button
        EditField_offset                matlab.ui.control.NumericEditField
        OffsetCheckBox                  matlab.ui.control.CheckBox
        RestEditField                   matlab.ui.control.NumericEditField
        RestEditFieldLabel              matlab.ui.control.Label
        DurationEditField               matlab.ui.control.NumericEditField
        DurationEditFieldLabel          matlab.ui.control.Label
        MVCparametersLabel              matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        EditionPanel                    matlab.ui.container.Panel
        CleaningLabel                   matlab.ui.control.Label
        PlotMUfiringratesButton         matlab.ui.control.Button
        SavetheeditionLabel             matlab.ui.control.Label
        VisualisationLabel              matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        PlotMUspiketrainsButton         matlab.ui.control.Button
        RemoveflaggedMUandduplicatesButton  matlab.ui.control.Button
        ImportdataButton                matlab.ui.control.Button
        SelectfileButton                matlab.ui.control.Button
        EditField_saving_2              matlab.ui.control.EditField
        BiofeedbackSettingsPanel        matlab.ui.container.Panel
        SmoothingEditField              matlab.ui.control.NumericEditField
        SmoothingEditFieldLabel         matlab.ui.control.Label
        ImportMUfiltersButton           matlab.ui.control.Button
        QualityofthedecompositionLabel  matlab.ui.control.Label
        DisplaythePulsetrainsButton     matlab.ui.control.Button
        StartRasterPlotwithMUsButton    matlab.ui.control.Button
        TypeofbiofeedbacksLabel         matlab.ui.control.Label
        ForceparametersLabel            matlab.ui.control.Label
        StartDRofalltheMUsButton        matlab.ui.control.Button
        StartQuadrantwithMUsButton      matlab.ui.control.Button
        StartQuadrantwithCSTsButton     matlab.ui.control.Button
        NbiterationsEditField           matlab.ui.control.NumericEditField
        NbiterationsEditFieldLabel      matlab.ui.control.Label
        DurationrestEditField           matlab.ui.control.NumericEditField
        DurationrestEditFieldLabel      matlab.ui.control.Label
        DurationplateauEditField        matlab.ui.control.NumericEditField
        DurationplateauEditFieldLabel   matlab.ui.control.Label
        DurationrampEditField           matlab.ui.control.NumericEditField
        DurationrampEditFieldLabel      matlab.ui.control.Label
        MVCtargetEditField              matlab.ui.control.NumericEditField
        MVCtargetEditFieldLabel         matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        VisualizationPanel              matlab.ui.container.Panel
        InitializeSAGAButton            matlab.ui.control.Button
        DisconnectSAGAButton            matlab.ui.control.Button
        GriddisplayedDropDownLabel      matlab.ui.control.Label
        GriddisplayedDropDown           matlab.ui.control.DropDown
        UIAxes3                         matlab.ui.control.UIAxes
        ManualeditionPanel              matlab.ui.container.Panel
        EditField_5                     matlab.ui.control.EditField
        ReevaluatewindowButton          matlab.ui.control.Button
        ScrollrightButton               matlab.ui.control.Button
        ZoomoutButton                   matlab.ui.control.Button
        ZoominButton                    matlab.ui.control.Button
        ScrollleftButton                matlab.ui.control.Button
        DeleteDRButton                  matlab.ui.control.Button
        DeletespikesButton              matlab.ui.control.Button
        AddspikesButton                 matlab.ui.control.Button
        FlagMUfordeletionButton         matlab.ui.control.Button
        RemoveoutliersButton            matlab.ui.control.Button
        MUdisplayedDropDownLabel        matlab.ui.control.Label
        MUdisplayedDropDown             matlab.ui.control.DropDown
        UIAxesDR                        matlab.ui.control.UIAxes
        UIAxesSpiketrain                matlab.ui.control.UIAxes
        VisualizationBiofeedback        matlab.ui.container.Panel
        InitializeSAGAButton_2          matlab.ui.control.Button
        YaxisdisplayedDropDownLabel     matlab.ui.control.Label
        YaxisdisplayedDropDown          matlab.ui.control.DropDown
        DisconnectSAGAButton_2          matlab.ui.control.Button
        XaxisdisplayedDropDownLabel     matlab.ui.control.Label
        XaxisdisplayedDropDown          matlab.ui.control.DropDown
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        RecordingTab                    matlab.ui.container.Tab
        TrainingTab                     matlab.ui.container.Tab
        EditionTab                      matlab.ui.container.Tab
        BiofeedbackTab                  matlab.ui.container.Tab
    end

    
    properties (Access = private)
        Pathname            % Folder where files will be saved      
        ComParameters       % Structure to save the parameters to interface with SAGA
        RecordParameters    % Structure to save the recording parameters (masks, muscles, MVC, offset)
        DecompParameters    % Structure to save the parameters of the decomposition (MU filters, extension matrix, centroids)
        OnlineParameters    % Structure to save the parameters of the online feedback

        filename2           % File to edit
        pathname2           % Folder where edited data will be saved
        MUedition           % Structure to save the data for MU edition accross contractions and intensities
        graphstart          % first time point of the graph for edition
        graphend            % last time point of the graph for edition
        roi                 % Region of interest for edition
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(pwd)
            addpath([pwd '\lib\'])
        end

        % Button down function: BiofeedbackTab
        function BiofeedbackTabButtonDown(app, event)
            app.BiofeedbackSettingsPanel.Visible = 'on';
            app.EditionPanel.Visible = 'off';
            app.ManualeditionPanel.Visible = 'off';
            app.TrainingSettingsPanel.Visible = 'off';
            app.RecordingSettingsPanel.Visible = 'off';
            app.VisualizationPanel.Visible = 'off';
            app.VisualizationBiofeedback.Visible = 'on';
        end

        % Button down function: EditionTab
        function EditionTabButtonDown(app, event)
            app.BiofeedbackSettingsPanel.Visible = 'off';
            app.EditionPanel.Visible = 'on';
            app.ManualeditionPanel.Visible = 'on';
            app.TrainingSettingsPanel.Visible = 'off';
            app.RecordingSettingsPanel.Visible = 'off';
            app.VisualizationPanel.Visible = 'off';
            app.VisualizationBiofeedback.Visible = 'off';
        end

        % Button down function: RecordingTab
        function RecordingTabButtonDown(app, event)
            app.BiofeedbackSettingsPanel.Visible = 'off';
            app.EditionPanel.Visible = 'off';
            app.ManualeditionPanel.Visible = 'off';
            app.TrainingSettingsPanel.Visible = 'off';
            app.RecordingSettingsPanel.Visible = 'on';
            app.VisualizationPanel.Visible = 'on';
            app.VisualizationBiofeedback.Visible = 'off';
        end

        % Button down function: TrainingTab
        function TrainingTabButtonDown(app, event)
            app.BiofeedbackSettingsPanel.Visible = 'off';
            app.EditionPanel.Visible = 'off';
            app.ManualeditionPanel.Visible = 'off';
            app.TrainingSettingsPanel.Visible = 'on';
            app.RecordingSettingsPanel.Visible = 'off';
            app.VisualizationPanel.Visible = 'on';
            app.VisualizationBiofeedback.Visible = 'off';
        end

        % Button pushed function: SelectfolderButton
        function SelectfolderButtonPushed(app, event)
            app.UIFigure.Visible = 'off'; 
            app.Pathname = uigetdir;
            app.UIFigure.Visible = 'on';
            version = ispc;
            if version == 1
                app.EditField_saving.Value = [app.Pathname '\'];
            else
                app.EditField_saving.Value = [app.Pathname '/'];
            end
        end

        % Value changed function: Grid1CheckBox
        function Grid1CheckBoxValueChanged(app, event)
            if app.Grid1CheckBox.Value == 1 && str2double(app.ChpergridDropDown.Value) == 32
                app.EditField_1.Editable = 'on';
                app.Grid2CheckBox.Enable = 'on';
                app.RecordParameters.ngrid = 1;
                app.InitializeSAGAButton.Enable = 'on';
                app.ComParameters.nchannel = 41;
                app.ComParameters.gridchannels = 2:1:33;
            elseif app.Grid1CheckBox.Value == 1 && str2double(app.ChpergridDropDown.Value) == 64
                app.EditField_1.Editable = 'on';
                app.RecordParameters.ngrid = 1;
                app.InitializeSAGAButton.Enable = 'on';
                app.ComParameters.nchannel = 73;
                app.ComParameters.gridchannels = 2:1:65; 
            else
                app.EditField_1.Editable = 'off';
                app.Grid2CheckBox.Enable = 'off';
                app.EditField_1.Value = '';
                app.EditField_2.Value = '';
                app.RecordParameters.ngrid = 0;
                app.GriddisplayedDropDown.Items = {'No Muscles'};
                app.GriddisplayedDropDown.Value = 'No Muscles';
                app.StartVisualizationChecknoiseButton.Enable = 'off';
                app.StartVisualizationCheckEMGButton.Enable = 'off';
                app.InitializeSAGAButton.Enable = 'off';
            end
        end

        % Value changed function: Grid2CheckBox
        function Grid2CheckBoxValueChanged(app, event)
            if app.Grid2CheckBox.Value == 1
                app.EditField_2.Editable = 'on';
                app.RecordParameters.ngrid = 2;
                app.ComParameters.nchannel = 73;
                app.ComParameters.gridchannels = 2:1:65;
            else
                app.EditField_2.Editable = 'off';
                app.Grid2CheckBox.Enable = 'off';
                app.EditField_2.Value = '';
                app.RecordParameters.ngrid = 1;
                app.ComParameters.nchannel = 41;
                app.ComParameters.gridchannels = 2:33;
            end
        end

        % Value changed function: EditField_1
        function EditField_1ValueChanged(app, event)
            app.GriddisplayedDropDown.Items = {'1'};
            app.GriddisplayedDropDown.Value = '1';
            app.RecordParameters.Muscles = {app.EditField_1.Value};
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.GriddisplayedDropDown.Items = {'1', '2'};
            app.RecordParameters.Muscles = {app.EditField_1.Value, app.EditField_2.Value};
        end

        % Value changed function: ChpergridDropDown
        function ChpergridDropDownValueChanged(app, event)
            if str2double(app.ChpergridDropDown.Value) == 32
                if app.Grid1CheckBox.Value == 1
                    app.Grid2CheckBox.Enable = 'on';
                end
            else
                app.Grid2CheckBox.Value = 0;
                app.Grid2CheckBox.Enable = 'off';
                app.EditField_2.Value = '';
                app.EditField_2.Editable = 'off';
                if app.Grid1CheckBox.Value == 1
                    app.RecordParameters.ngrid = 1;
                    app.ComParameters.nchannel = 73;
                    app.ComParameters.gridchannels = 2:1:65;
                else
                    app.RecordParameters.ngrid = 0;
                    app.GriddisplayedDropDown.Items = {'No Muscles'};
                    app.GriddisplayedDropDown.Value = 'No Muscles';
                    app.StartVisualizationChecknoiseButton.Enable = 'off';
                    app.StartVisualizationCheckEMGButton.Enable = 'off';
                    app.InitializeSAGAButton.Enable = 'off';
                end
            end
        end

        % Button pushed function: InitializeSAGAButton
        function InitializeSAGAButtonPushed(app, event)
            app.ComParameters.fsamp = str2double(app.FrequencyDropDown.Value);
            if app.ComParameters.fsamp == 4096
                Dividers = 0;
            elseif app.ComParameters.fsamp == 2048
                Dividers = 1;
            elseif app.ComParameters.fsamp == 1024
                Dividers = 2;
            else
                Dividers = 3;
            end
            
            % If the script has been terminated manually, it can occur that the device
            % keeps sampling. To be sure, a lib.cleanUp() is performed prior to running
            % the example.
            if exist('app.ComParameters.lib', 'var')
                disp('[N/A] Ensure library is cleaned...')
                app.ComParameters.lib.cleanUp();
            end

            % Initialize the library
            app.ComParameters.lib = TMSiSAGA.Library();

            % Specify desired device configuration
            app.ComParameters.deviceConfig = struct('ImpedanceMode', false,...
                'ReferenceMethod', 'common', ...
                'BaseSampleRate', 4096, ...
                'Dividers', {{'uni', Dividers; 'bip', Dividers; 'aux', Dividers;}});

            % Specify desired channel configuration
            app.ComParameters.channelConfig=struct('uni', [1:str2double(app.ChpergridDropDown.Value)*app.RecordParameters.ngrid], 'bip', [1:4], 'aux', [1:3], 'acc',0, 'dig', 0);

            % Get a single device from the connected devices
            % possible interfaces 'usb'/'network' and 'electrical'/'optical/'wifi'
            try
                % Get the device
                app.ComParameters.device = app.ComParameters.lib.getFirstAvailableDevice('usb', app.DockcomDropDown.Value);
            catch
                % Get the device
                app.ComParameters.device = app.ComParameters.lib.getFirstAvailableDevice('usb', 'electrical');

                % Open a connection to the device
                app.ComParameters.device.connect();
    
                % Change interface with docking station
                app.ComParameters.device.changeDataRecorderInterfaceTo(app.DockcomDropDown.Value)

                % Get the device with selected docking interface
                app.ComParameters.device = app.ComParameters.lib.getFirstAvailableDevice('usb', app.DockcomDropDown.Value);
            end

            % Open a connection to the device
            app.ComParameters.device.connect();

            % Set device configuration
            app.ComParameters.device.setDeviceConfig(app.ComParameters.deviceConfig);

            % Set channel configuration
            app.ComParameters.device.setChannelConfig(app.ComParameters.channelConfig);

            % Update the UI for the next acquisition
            app.GriddisplayedDropDown.Enable = 'on';
            app.InitializeSAGAButton.Enable = 'off';
            app.InitializeSAGAButton_2.Enable = 'off';
            app.DisconnectSAGAButton.Enable = 'on';
            app.DisconnectSAGAButton_2.Enable = 'on';
            app.StartVisualizationCheckEMGButton.Enable = 'on';
            app.StartVisualizationChecknoiseButton.Enable = 'on';
            app.StartButton.Enable = 'on';
            app.StartMVCButton.Enable = 'on';
            app.OffsetCheckBox.Enable = 'on';
            app.XaxisdisplayedDropDown.Enable = 'on';
            app.YaxisdisplayedDropDown.Enable = 'on';
            app.StartQuadrantwithCSTsButton.Enable = 'on';
            app.StartQuadrantwithMUsButton.Enable = 'on';
            app.StartRasterPlotwithMUsButton.Enable = 'on';
            app.StartDRofalltheMUsButton.Enable = 'on';
        end

        % Button pushed function: DisconnectSAGAButton
        function DisconnectSAGAButtonPushed(app, event)
            % Stop sampling on the device
            app.ComParameters.device.stop();

            % Disconnect from device
            app.ComParameters.device.disconnect();

            % clean up and unload the library
            app.ComParameters.lib.cleanUp();

            app.InitializeSAGAButton.Enable = 'on';
            app.InitializeSAGAButton_2.Enable = 'on';
            app.DisconnectSAGAButton.Enable = 'off';
            app.DisconnectSAGAButton_2.Enable = 'off';
            app.StartVisualizationCheckEMGButton.Enable = 'off';
            app.StartVisualizationChecknoiseButton.Enable = 'off';
            app.StartButton.Enable = 'off';
            app.StartMVCButton.Enable = 'off';
            app.OffsetCheckBox.Enable = 'off';
            app.StartQuadrantwithCSTsButton.Enable = 'off';
            app.StartQuadrantwithMUsButton.Enable = 'off';
            app.StartRasterPlotwithMUsButton.Enable = 'off';
            app.StartDRofalltheMUsButton.Enable = 'off';
        end

        % Button pushed function: StartVisualizationChecknoiseButton
        function StartVisualizationChecknoiseButtonPushed(app, event)
            
            % Set the properties of the axes for data viz
            app.UIAxes3.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes3.YColor = [0.9412 0.9412 0.9412];
            xlabel(app.UIAxes3,'Time');
            ylabel(app.UIAxes3,'Channels');
            
            % record 15 seconds of data to assess the baseline noise
            nwin = floor(15*str2double(app.FrequencyDropDown.Value));
            nwinbuffmax = floor(str2double(app.FrequencyDropDown.Value)*4.8);
            i = 1;
            nwinbuff = 1;

            % Set the properties of the filter
            [c,d] = butter(3,10/(str2double(app.FrequencyDropDown.Value)/2),'high');

            % Pre allocate empty matrices
            time = linspace(0,5,5*str2double(app.FrequencyDropDown.Value));
            buffer = zeros(str2double(app.FrequencyDropDown.Value)*5,str2double(app.ChpergridDropDown.Value));
            EMG = zeros(str2double(app.ChpergridDropDown.Value)*app.RecordParameters.ngrid,str2double(app.FrequencyDropDown.Value)*15+2000);
            offset = (1:1:str2double(app.ChpergridDropDown.Value))';
            EMGfilter = zeros(str2double(app.FrequencyDropDown.Value)*15+1000,str2double(app.ChpergridDropDown.Value)*app.RecordParameters.ngrid);

            % Code within the try-catch to ensure that all devices are stopped and
            % closed properly in case of a failure.
            try

                app.ComParameters.device.start();

                while i <= nwin -500
                    [samples, num_sets] = app.ComParameters.device.sample();
                    if num_sets > 0
                        % Filter can not be applied if number of sampel too low
                        while num_sets < 10
                            [samplesT, num_setsT] = app.ComParameters.device.sample();
                            samples = [samples samplesT];
                            num_sets = num_sets + num_setsT;
                        end
                        EMG(:,i:i+num_sets-1) = samples(app.ComParameters.gridchannels, :);
                        EMGfilter(1:i+num_sets-1,:) = filtfilt(c,d,EMG(:,1:i+num_sets-1)');

                        if nwinbuff + num_sets <= nwinbuffmax
                            buffer(nwinbuff:nwinbuff+num_sets-1,:) = (EMGfilter(i:i+num_sets-1,(str2double(app.GriddisplayedDropDown.Value)-1)*str2double(app.ChpergridDropDown.Value)+1:str2double(app.GriddisplayedDropDown.Value)*str2double(app.ChpergridDropDown.Value))' * 0.001 + ones(str2double(app.ChpergridDropDown.Value),num_sets).*offset)';
                        else
                            cutbuff = nwinbuffmax - nwinbuff;
                            buffer(nwinbuff:nwinbuffmax-1,:) = (EMGfilter(i:i+cutbuff-1,(str2double(app.GriddisplayedDropDown.Value)-1)*str2double(app.ChpergridDropDown.Value)+1:str2double(app.GriddisplayedDropDown.Value)*str2double(app.ChpergridDropDown.Value))' * 0.001 + ones(str2double(app.ChpergridDropDown.Value),cutbuff).*offset)';                            
                        end

                        plot(app.UIAxes3, time, buffer, 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
                        app.UIAxes3.XLim = [0 5];
                        app.UIAxes3.YLim = [0 str2double(app.ChpergridDropDown.Value)+2];
                        if nwinbuff + num_sets <= nwinbuffmax
                            line(app.UIAxes3, [time(nwinbuff+num_sets-1) time(nwinbuff+num_sets-1)], [0 app.RecordParameters.ngrid*str2double(app.ChpergridDropDown.Value)], 'LineWidth', 1, 'Color', [0.81 0.50 1.00]);
                        end
                        drawnow limitrate
                        i = i + num_sets;
                        if nwinbuff+num_sets < nwinbuffmax
                            nwinbuff = nwinbuff+num_sets;
                        else
                            nwinbuff = 1;
                        end
                    end
                end
                % Stop sampling on the device
                app.ComParameters.device.stop();

                % Update device configuration
                app.ComParameters.device.setDeviceConfig(app.ComParameters.deviceConfig);

                EMG = EMG(:,1:i);
                % Check EMG noise
                Noisecheck = zeros(str2double(app.ChpergridDropDown.Value),1);
                for j = 1:str2double(app.ChpergridDropDown.Value)
                    Noisecheck(j) = rms(EMGfilter(str2double(app.FrequencyDropDown.Value):end-str2double(app.FrequencyDropDown.Value),(str2double(app.GriddisplayedDropDown.Value)-1)*str2double(app.ChpergridDropDown.Value)+j)'/1000);
                end
                bar(app.UIAxes3,Noisecheck)
                app.UIAxes3.XLim = [0 str2double(app.ChpergridDropDown.Value)+1];
                app.UIAxes3.YLim = [0 max(Noisecheck)];
                xlabel(app.UIAxes3,'Channels');
                ylabel(app.UIAxes3,'RMS amplitude');
                drawnow

                % Save the file and update the UI for the next acquisition
                Musclename = app.RecordParameters.Muscles;
                save([app.EditField_saving.Value 'EMGChecking ' replace(datestr(datetime('now')),':','_') '.mat'],'EMG', 'Musclename');

            catch e
                % In case of an error close all still active devices and clean up
                % library itself
                app.ComParameters.lib.cleanUp();

                % Update the UI for the next acquisition
                app.InitializeSAGAButton.Enable = 'on';
                app.DisconnectSAGAButton.Enable = 'off';
                app.StartVisualizationCheckEMGButton.Enable = 'off';
                app.StartVisualizationChecknoiseButton.Enable = 'off';
                app.StartButton.Enable = 'off';
                app.StartMVCButton.Enable = 'off';
                app.OffsetCheckBox.Enable = 'off';

                rethrow(e)
            end

        end

        % Button pushed function: StartVisualizationCheckEMGButton
        function StartVisualizationCheckEMGButtonPushed(app, event)
            
            % Set the properties of the axes for data viz
            app.UIAxes3.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes3.YColor = [0.9412 0.9412 0.9412];
            xlabel(app.UIAxes3,'Time');
            ylabel(app.UIAxes3,'Channels');

            % Set the properties of the filter
            [c,d] = butter(3,10/(str2double(app.FrequencyDropDown.Value)/2),'high');

            % record 30 seconds of data to assess the quality of the EMG
            % signals and remove noisy channels
            nwin = floor(30*str2double(app.FrequencyDropDown.Value));
            nwinbuffmax = floor(str2double(app.FrequencyDropDown.Value)*4.8);
            i = 1;
            nwinbuff = 1;

            % Pre allocate empty matrices
            time = linspace(0,5,5*str2double(app.FrequencyDropDown.Value));
            buffer = zeros(str2double(app.FrequencyDropDown.Value)*5,str2double(app.ChpergridDropDown.Value));
            EMG = zeros(str2double(app.ChpergridDropDown.Value)*app.RecordParameters.ngrid,str2double(app.FrequencyDropDown.Value)*30+3000);
            offset = (1:1:str2double(app.ChpergridDropDown.Value))';
            EMGfilter = zeros(str2double(app.FrequencyDropDown.Value)*30+1000,str2double(app.ChpergridDropDown.Value)*app.RecordParameters.ngrid);

            % Code within the try-catch to ensure that all devices are stopped and
            % closed properly in case of a failure.
            try

                app.ComParameters.device.start();
                pause(0.250)
                while i <= nwin -2000
                    [samples, num_sets] = app.ComParameters.device.sample();
                    if num_sets > 0
                        % Filter can not be applied if number of sampel too low
                        while num_sets < 10
                            [samplesT, num_setsT] = app.ComParameters.device.sample();
                            samples = [samples samplesT];
                            num_sets = num_sets + num_setsT;
                        end
                        EMG(:,i:i+num_sets-1) = samples(app.ComParameters.gridchannels, :);
                        EMGfilter(1:i+num_sets-1,:) = filtfilt(c,d,EMG(:,1:i+num_sets-1)');

                        if nwinbuff + num_sets <= nwinbuffmax
                            buffer(nwinbuff:nwinbuff+num_sets-1,:) = (EMGfilter(i:i+num_sets-1,(str2double(app.GriddisplayedDropDown.Value)-1)*str2double(app.ChpergridDropDown.Value)+1:str2double(app.GriddisplayedDropDown.Value)*str2double(app.ChpergridDropDown.Value))' * 0.001 + ones(str2double(app.ChpergridDropDown.Value),num_sets).*offset)';
                        else
                            cutbuff = nwinbuffmax - nwinbuff;
                            buffer(nwinbuff:nwinbuffmax-1,:) = (EMGfilter(i:i+cutbuff-1,(str2double(app.GriddisplayedDropDown.Value)-1)*str2double(app.ChpergridDropDown.Value)+1:str2double(app.GriddisplayedDropDown.Value)*str2double(app.ChpergridDropDown.Value))' * 0.001 + ones(str2double(app.ChpergridDropDown.Value),cutbuff).*offset)';                            
                        end

                        plot(app.UIAxes3, time, buffer, 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
                        app.UIAxes3.XLim = [0 5];
                        app.UIAxes3.YLim = [0 str2double(app.ChpergridDropDown.Value)+2];
                        if nwinbuff + num_sets <= nwinbuffmax
                            line(app.UIAxes3, [time(nwinbuff+num_sets-1) time(nwinbuff+num_sets-1)], [0 app.RecordParameters.ngrid*str2double(app.ChpergridDropDown.Value)], 'LineWidth', 1, 'Color', [0.81 0.50 1.00]);
                        end
                        drawnow limitrate
                        i = i + num_sets;
                        if nwinbuff+num_sets < nwinbuffmax
                            nwinbuff = nwinbuff+num_sets;
                        else
                            nwinbuff = 1;
                        end
                    end
                end
                % Stop sampling on the device
                app.ComParameters.device.stop();

                % Update device configuration
                app.ComParameters.device.setDeviceConfig(app.ComParameters.deviceConfig);

                EMG = EMG(:,1:i);
                % Check EMG
                for i = 1:app.RecordParameters.ngrid
                    EMGcheck = zeros(8,size(EMGfilter,2)*str2double(app.ChpergridDropDown.Value)/8);
                    for j = 1:str2double(app.ChpergridDropDown.Value)/8
                        EMGcheck(:,(j-1)*size(EMGfilter,1)+1:j*size(EMGfilter,1)) = EMGfilter(:,(i-1)*str2double(app.ChpergridDropDown.Value)+j*8-7:(i-1)*str2double(app.ChpergridDropDown.Value)+j*8)';
                    end
                    offset2 = ones(8,size(EMGcheck,2)) .* (1:1:8)';
                    EMGcheck = EMGcheck * 0.001 + offset2;
                    plot(app.UIAxes3,EMGcheck')
                    line(app.UIAxes3,[size(EMG,2) size(EMG,2)], [0 9], 'LineWidth', 2, 'Color', [0.81 0.50 1.00])
                    line(app.UIAxes3,[2*size(EMG,2) 2*size(EMG,2)], [0 9], 'LineWidth', 2, 'Color', [0.81 0.50 1.00])
                    line(app.UIAxes3,[3*size(EMG,2) 3*size(EMG,2)], [0 9], 'LineWidth', 2, 'Color', [0.81 0.50 1.00])
                    if str2double(app.ChpergridDropDown.Value) == 64
                        line(app.UIAxes3,[4*size(EMG,2) 4*size(EMG,2)], [0 9], 'LineWidth', 2, 'Color', [0.81 0.50 1.00])
                        line(app.UIAxes3,[5*size(EMG,2) 5*size(EMG,2)], [0 9], 'LineWidth', 2, 'Color', [0.81 0.50 1.00])
                        line(app.UIAxes3,[6*size(EMG,2) 6*size(EMG,2)], [0 9], 'LineWidth', 2, 'Color', [0.81 0.50 1.00])
                        line(app.UIAxes3,[7*size(EMG,2) 7*size(EMG,2)], [0 9], 'LineWidth', 2, 'Color', [0.81 0.50 1.00]) 
                    end
                    app.UIAxes3.XLim = [0 size(EMGcheck,2)];
                    app.UIAxes3.YLim = [0 9];
                    xlabel(app.UIAxes3,'Columns');
                    ylabel(app.UIAxes3,'Rows');
                    drawnow
                    
                    if str2double(app.ChpergridDropDown.Value) == 32
                        prompt   = {'col #1', 'col #2', 'col #3', 'col #4'};
                    else
                        prompt   = {'col #1', 'col #2', 'col #3', 'col #4', 'col #5', 'col #6', 'col #7', 'col #8'};
                    end
                    name     = 'Select the bad channels';
                    dlg_emg  = inputdlg(prompt, name);

                    app.RecordParameters.DiscardChannels{i} = zeros(str2double(app.ChpergridDropDown.Value),1);
                    for j = 1:4
                        app.RecordParameters.DiscardChannels{i}(str2num(dlg_emg{j}) + ((j-1)*8),1) = 1;
                    end
                    close;
                end

                % Save the file and update the UI for the next acquisition
                DisChannels = app.RecordParameters.DiscardChannels;
                Musclename = app.RecordParameters.Muscles;
                EMG = EMG(:,1:end-1000);
                save([app.EditField_saving.Value 'EMGChecking ' replace(datestr(datetime('now')),':','_') '.mat'],'EMG', 'DisChannels', 'Musclename');

            catch e
                % In case of an error close all still active devices and clean up
                % library itself
                app.ComParameters.lib.cleanUp();

                % Update the UI for the next acquisition
                app.InitializeSAGAButton.Enable = 'on';
                app.DisconnectSAGAButton.Enable = 'off';
                app.StartVisualizationCheckEMGButton.Enable = 'off';
                app.StartVisualizationChecknoiseButton.Enable = 'off';
                app.StartButton.Enable = 'off';
                app.StartMVCButton.Enable = 'off';
                app.OffsetCheckBox.Enable = 'off';

                rethrow(e)
            end

        end

        % Value changed function: OffsetCheckBox
        function OffsetCheckBoxValueChanged(app, event)
            if app.OffsetCheckBox.Value == 1

                nwin = (3*str2double(app.FrequencyDropDown.Value));
                i = 1;

                % Pre allocate an empty vector
                force = zeros(1,nwin+1000);

                % Code within the try-catch to ensure that all devices are stopped and
                % closed properly in case of a failure.
                try

                    app.ComParameters.device.start();
                    while i <= nwin
                        [samples, num_sets] = app.ComParameters.device.sample();
                        if num_sets > 0
                            force(:,i:i+num_sets-1) = samples(app.ComParameters.gridchannels(end)+4+str2double(app.ForcechDropDown.Value), :);

                            i = i + num_sets;
                        end
                    end
                    % Stop sampling on the device
                    app.ComParameters.device.stop();

                    % Update device configuration
                    app.ComParameters.device.setDeviceConfig(app.ComParameters.deviceConfig)

                    force = force(:,1:i);

                    app.RecordParameters.offsetforce = mean(force(str2double(app.FrequencyDropDown.Value):end));
                    app.EditField_offset.Value = app.RecordParameters.offsetforce;
                catch
                    % In case of an error close all still active devices and clean up
                    % library itself
                    app.ComParameters.lib.cleanUp();

                    % Update the UI for the next acquisition
                    app.InitializeSAGAButton.Enable = 'on';
                    app.DisconnectSAGAButton.Enable = 'off';
                    app.StartVisualizationCheckEMGButton.Enable = 'off';
                    app.StartVisualizationChecknoiseButton.Enable = 'off';
                    app.StartButton.Enable = 'off';
                    app.StartMVCButton.Enable = 'off';
                    app.OffsetCheckBox.Enable = 'off';
                end
            else
                app.RecordParameters.offsetforce = 0;
                app.EditField_offset.Value = app.RecordParameters.offsetforce;
            end
        end

        % Button pushed function: StartMVCButton
        function StartMVCButtonPushed(app, event)
            % Set the properties of the gauge for data viz
            app.Gauge.Limits = [0 5];
            app.Gauge.MajorTicks = 0:1:5;

            % Generate the timeline for n trials separated by rest
            trialength = str2double(app.FrequencyDropDown.Value) * app.DurationEditField.Value * 3 + str2double(app.FrequencyDropDown.Value) * app.RestEditField.Value * 2 + str2double(app.FrequencyDropDown.Value) * 10;
            nwin = trialength;
            i = 1;

            MVCBeg = zeros(str2double(app.FrequencyDropDown.Value) * 10,1);
            MVCtrial = ones(str2double(app.FrequencyDropDown.Value) * app.DurationEditField.Value,1);
            MVCrest = zeros(str2double(app.FrequencyDropDown.Value) * app.RestEditField.Value,1);
            MVClight = [MVCBeg;MVCtrial;MVCrest;MVCtrial;MVCrest;MVCtrial;zeros(1000,1)];     
            TimeBeg = linspace(10,0,10*str2double(app.FrequencyDropDown.Value));
            Timetrial = zeros(1,str2double(app.FrequencyDropDown.Value) * app.DurationEditField.Value);
            Timerest = linspace(app.RestEditField.Value,0,app.RestEditField.Value*str2double(app.FrequencyDropDown.Value));
            Time = [TimeBeg Timetrial Timerest Timetrial Timerest Timetrial zeros(1,1000)];

            % Pre allocate empty matrices
            EMG = zeros(app.RecordParameters.ngrid*str2double(app.ChpergridDropDown.Value),trialength+1000);
            Force = zeros(1,trialength+1000);

            % Code within the try-catch to ensure that all devices are stopped and
            % closed properly in case of a failure.
            try

                app.ComParameters.device.start();
                while i <= nwin
                    [samples, num_sets] = app.ComParameters.device.sample();
                    if num_sets > 0
                        EMG(:,i:i+num_sets-1) = samples(app.ComParameters.gridchannels, :);
                        Force(:,i:i+num_sets-1) = samples(app.ComParameters.gridchannels(end)+4+str2double(app.ForcechDropDown.Value), :) - app.RecordParameters.offsetforce;

                        if i+num_sets-1 > str2double(app.FrequencyDropDown.Value)/8
                            app.Gauge.Value = abs(mean(Force(i+num_sets-1-str2double(app.FrequencyDropDown.Value)/8:i+num_sets-1))) * 0.0005;
                        else
                            app.Gauge.Value = abs(mean(Force(1:i+num_sets-1))) * 0.0005;
                        end
                        app.EditField_MVC.Value = num2str(round(Time(i+num_sets-1),1));
                        if MVClight(i+num_sets-1) == 1
                            app.StartMVCButton.BackgroundColor = 'green';
                        else
                            app.StartMVCButton.BackgroundColor = 'red';
                        end
                        drawnow
                        i = i + num_sets;
                    end
                end
                % Stop sampling on the device
                app.ComParameters.device.stop();

                % Update device configuration
                app.ComParameters.device.setDeviceConfig(app.ComParameters.deviceConfig)

                EMG = EMG(:,1:i);
                Force = Force(:,1:i);

                % remove the first second where the initialization of the amplifier occurs
                EMG = EMG(:,str2double(app.FrequencyDropDown.Value):end);
                Force = Force(str2double(app.FrequencyDropDown.Value):end);

                % estimate the MVC over a moving average of 250 ms
                app.RecordParameters.MVC = max(abs(movmean(Force, str2double(app.FrequencyDropDown.Value)*0.25)));
                % if no force sensor
                if app.RecordParameters.MVC == 0
                    app.RecordParameters.MVC = 1;
                end
                MVCforce = app.RecordParameters.MVC;
                Offset_force = app.RecordParameters.offsetforce;
                DisChannels = app.RecordParameters.DiscardChannels;
                Musclename = app.RecordParameters.Muscles;

                % save the file
                save([app.EditField_saving.Value 'MVC ' replace(datestr(datetime('now')),':','_') '.mat'], 'EMG', 'MVCforce', 'Force', 'Offset_force', 'Time', 'MVClight', 'DisChannels', 'Musclename');
            catch e
                % In case of an error close all still active devices and clean up
                % library itself
                app.ComParameters.lib.cleanUp();

                % Update the UI for the next acquisition
                app.InitializeSAGAButton.Enable = 'on';
                app.DisconnectSAGAButton.Enable = 'off';
                app.StartVisualizationCheckEMGButton.Enable = 'off';
                app.StartVisualizationChecknoiseButton.Enable = 'off';
                app.StartButton.Enable = 'off';
                app.StartMVCButton.Enable = 'off';
                app.OffsetCheckBox.Enable = 'off';

                rethrow(e)
            end
        end

        % Value changed function: DurationtrainingEditField
        function DurationtrainingEditFieldValueChanged(app, event)
            fsamp = str2double(app.FrequencyDropDown.Value);
            app.DecompParameters.durationtraining = app.DurationtrainingEditField.Value * fsamp + 2*10*fsamp + 2*6*fsamp;
            time = linspace(0,app.DecompParameters.durationtraining/fsamp,app.DecompParameters.durationtraining);
            rest = zeros(1,6*fsamp);
            rampup = linspace(0,app.MVCTargetEditField.Value,10*fsamp);
            rampdown = linspace(app.MVCTargetEditField.Value,0,10*fsamp);
            plateau = ones(1,app.DurationtrainingEditField.Value * fsamp) * app.MVCTargetEditField.Value;
            app.DecompParameters.target = [rest rampup plateau rampdown rest];
            plot(app.UIAxes,time,app.DecompParameters.target,'Linewidth', 2, 'Color', [0.56 0.62 0.85]);
            app.UIAxes.XLim = [0 app.DecompParameters.durationtraining/fsamp];
            app.UIAxes.YLim = [0 app.MVCTargetEditField.Value + 1];
            app.UIAxes.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes.YColor = [0.9412 0.9412 0.9412];
            xlabel(app.UIAxes,'Time');
            ylabel(app.UIAxes,'Relative force');
        end

        % Value changed function: MVCTargetEditField
        function MVCTargetEditFieldValueChanged(app, event)
            fsamp = str2double(app.FrequencyDropDown.Value);
            app.DecompParameters.durationtraining = app.DurationtrainingEditField.Value * fsamp + 2*10*fsamp + 2*6*fsamp;
            time = linspace(0,app.DecompParameters.durationtraining/fsamp,app.DecompParameters.durationtraining);
            rest = zeros(1,6*fsamp);
            rampup = linspace(0,app.MVCTargetEditField.Value,10*fsamp);
            rampdown = linspace(app.MVCTargetEditField.Value,0,10*fsamp);
            plateau = ones(1,app.DurationtrainingEditField.Value * fsamp) * app.MVCTargetEditField.Value;
            app.DecompParameters.target = [rest rampup plateau rampdown rest];
            plot(app.UIAxes,time,app.DecompParameters.target,'Linewidth', 2, 'Color', [0.56 0.62 0.85]);
            app.UIAxes.XLim = [0 app.DecompParameters.durationtraining/fsamp];
            app.UIAxes.YLim = [0 app.MVCTargetEditField.Value + 1];
            app.UIAxes.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes.YColor = [0.9412 0.9412 0.9412];
            xlabel(app.UIAxes,'Time');
            ylabel(app.UIAxes,'Relative force');
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            % Set the properties of the axes for data viz
            app.UIAxes3.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes3.YColor = [0.9412 0.9412 0.9412];
            xlabel(app.UIAxes3,'Time');
            ylabel(app.UIAxes3,'Channels');

            nwin = app.DecompParameters.durationtraining-floor(str2double(app.FrequencyDropDown.Value)/32);
            nwinbuffmax = floor(str2double(app.FrequencyDropDown.Value)*5);
            i = 1;
            [c,d] = butter(3,10/(str2double(app.FrequencyDropDown.Value)/2),'high');

            % Pre allocate empty matrices
            timetot = linspace(0,app.DecompParameters.durationtraining/str2double(app.FrequencyDropDown.Value),app.DecompParameters.durationtraining);
            EMG = zeros(app.RecordParameters.ngrid*str2double(app.ChpergridDropDown.Value),app.DecompParameters.durationtraining);
            Force = NaN(1,app.DecompParameters.durationtraining);
            ForceDisp = NaN(1,app.DecompParameters.durationtraining);

            % Code within the try-catch to ensure that all devices are stopped and
            % closed properly in case of a failure.
            try

                app.ComParameters.device.start();
                while i <= nwin - nwinbuffmax
                    [samples, num_sets] = app.ComParameters.device.sample();
                    if num_sets > 0
                        EMG(:,i:i+num_sets-1) = samples(app.ComParameters.gridchannels, :);
                        Force(:,i:i+num_sets-1) = samples(app.ComParameters.gridchannels(end)+4+str2double(app.ForcechDropDown.Value),:) - app.RecordParameters.offsetforce;

                        if i <= nwinbuffmax
                            ForceDisp(:,1:i+num_sets-1) = movmean(Force(:,1:i+num_sets-1),(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)));
                            plot(app.UIAxes3, timetot(1:str2double(app.FrequencyDropDown.Value)*10), abs(ForceDisp(1:str2double(app.FrequencyDropDown.Value)*10))/app.RecordParameters.MVC *100, ...
                                timetot(1:str2double(app.FrequencyDropDown.Value)*10), app.DecompParameters.target(1:str2double(app.FrequencyDropDown.Value)*10), ...
                                timetot(i+num_sets-1), abs(ForceDisp(i+num_sets-1))/app.RecordParameters.MVC * 100, 'o', 'MarkerSize', 20, 'LineWidth', 3, 'Color', [0.38 0.78 0.75]);
                            app.UIAxes3.XLim = [timetot(1) timetot(str2double(app.FrequencyDropDown.Value)*10)];
                            app.UIAxes3.YLim = [0 app.MVCTargetEditField.Value + 3];
                            plot(app.UIAxes, timetot, app.DecompParameters.target, timetot(i+num_sets-1), app.DecompParameters.target(i+num_sets-1), 'o', 'LineWidth', 2, 'MarkerSize', 20, 'Color', [0.56 0.62 0.85]);
                            drawnow limitrate
                        else
                            if i+num_sets-1+nwinbuffmax > app.DecompParameters.durationtraining
                                ForceDisp(:,i+num_sets-nwinbuffmax:i+num_sets-1) = movmean(Force(:,i+num_sets-nwinbuffmax:i+num_sets-1),round(str2double(app.FrequencyDropDown.Value)/str2double(app.ChpergridDropDown.Value)));
                                plot(app.UIAxes3, timetot(i+num_sets-nwinbuffmax:end), abs(ForceDisp(i+num_sets-nwinbuffmax:app.DecompParameters.durationtraining))/app.RecordParameters.MVC *100, ...
                                    timetot(i+num_sets-nwinbuffmax:end), app.DecompParameters.target(i+num_sets-nwinbuffmax:app.DecompParameters.durationtraining), ...
                                    timetot(i+num_sets-1), abs(Force(i+num_sets-1))/app.RecordParameters.MVC *100, 'o', 'MarkerSize', 20, 'LineWidth', 3, 'Color', [0.38 0.78 0.75]);
                                app.UIAxes3.XLim = [timetot(i+num_sets-nwinbuffmax) timetot(end)];
                                app.UIAxes3.YLim = [0 app.MVCTargetEditField.Value + 3];
                                plot(app.UIAxes, timetot, app.DecompParameters.target, timetot(i+num_sets-1), app.DecompParameters.target(i+num_sets-1), 'o', 'LineWidth', 2, 'MarkerSize', 20, 'Color', [0.56 0.62 0.85]);
                                drawnow limitrate
                            else
                                ForceDisp(:,i+num_sets-nwinbuffmax:i+num_sets-1) = movmean(Force(:,i+num_sets-nwinbuffmax:i+num_sets-1),round(str2double(app.FrequencyDropDown.Value)/str2double(app.ChpergridDropDown.Value)));
                                plot(app.UIAxes3, timetot(i+num_sets-nwinbuffmax:i+num_sets-1+nwinbuffmax), abs(ForceDisp(i+num_sets-nwinbuffmax:i+num_sets-1+nwinbuffmax))/app.RecordParameters.MVC *100, ...
                                    timetot(i+num_sets-nwinbuffmax:i+num_sets-1+nwinbuffmax), app.DecompParameters.target(i+num_sets-nwinbuffmax:i+num_sets-1+nwinbuffmax), ...
                                    timetot(i+num_sets-1), abs(Force(i+num_sets-1))/app.RecordParameters.MVC *100, 'o', 'MarkerSize', 20, 'LineWidth', 3, 'Color', [0.38 0.78 0.75]);
                                app.UIAxes3.XLim = [timetot(i+num_sets-nwinbuffmax) timetot(i+num_sets-1+nwinbuffmax)];
                                app.UIAxes3.YLim = [0 app.MVCTargetEditField.Value + 3];
                                plot(app.UIAxes, timetot, app.DecompParameters.target, timetot(i+num_sets-1), app.DecompParameters.target(i+num_sets-1), 'o', 'LineWidth', 2, 'MarkerSize', 20, 'Color', [0.56 0.62 0.85]);
                                drawnow limitrate
                            end
                        end
                        i = i + num_sets;
                    end
                end

                % Stop sampling on the device
                app.ComParameters.device.stop();

                % Update device configuration
                app.ComParameters.device.setDeviceConfig(app.ComParameters.deviceConfig);

                % Decomposition for training
                f = waitbar(0,'Decomposition...');
                nbextchan = 1500;
                EMG = EMG(:,1:i);
                Force = Force(:,1:i);
                signal.target = app.DecompParameters.target(:,1:i);


                offsetMU = 0;

                for i = 1:app.RecordParameters.ngrid
                    SIG = filtfilt(c,d,EMG((i-1)*str2double(app.ChpergridDropDown.Value)+1:i*str2double(app.ChpergridDropDown.Value),25*str2double(app.FrequencyDropDown.Value):25*str2double(app.FrequencyDropDown.Value)+app.DurationtrainingEditField.Value*str2double(app.FrequencyDropDown.Value))');
                    SIG = SIG(:,app.RecordParameters.DiscardChannels{i}==0)';

                    % Signal Extension
                    exFactor = round(nbextchan/size(SIG,1));
                    eSIG = extend(SIG,exFactor);

                    % Signal Whitening
                    [E, D] = pcaesig(eSIG);
                    [wSIG, ~, dewhiteningMatrix] = whiteesig(eSIG, E, D);

                    % Removing the edges
                    wSIG = wSIG(:,round(str2double(app.FrequencyDropDown.Value)):end-round(str2double(app.FrequencyDropDown.Value)));

                    % FastICA
                    % Initialize matrix B (n x m) n: separation vectors, m: iterations
                    % Initialize matrix MUFilters to only save the reliable filters
                    % Intialize SIL and PNR
                    B = zeros(size(wSIG,1), app.NbIterationsEditField.Value); % all separation vectors
                    MUFilters = zeros(size(wSIG,1), app.NbIterationsEditField.Value); % only reliable vectors
                    SIL = zeros(1,app.NbIterationsEditField.Value);
                    COV = zeros(1,app.NbIterationsEditField.Value);

                    % Find the index where the square of the summed whitened vectors is
                    % maximized and initialize W with the whitened observations at this time
                    idx1 = zeros(1,app.NbIterationsEditField.Value);

                    for j = 1:app.NbIterationsEditField.Value
                        if j == 1
                            X = wSIG; % Initialize X (whitened signal), then X: residual
                            actind = sum(X,1).^2;
                            [~, idx1(j)] = max(actind);
                            w = X(:, idx1(j)); % Initialize w
                        else
                            actind(idx1(j-1)) = 0; % remove the previous vector
                            [~, idx1(j)] = max(actind);
                            w = X(:, idx1(j)); % Initialize w
                        end

                        w = w - B * B' * w; % Orthogonalization
                        w = w / norm(w); % Normalization

                        %       3a: Fixed point algorithm (end when sparsness is maximized)
                        maxiter = 500; % max number of iterations for the fixed point algorithm
                        w = fixedpointalg(w, X, B , maxiter, 'logcosh');

                        % Step 4: Minimization of the CoV of discharge times (end when CoV is
                        % minimized)
                        % Initialize CoV (variation of interspike intervals, %) Step 4a => 4e
                        [~, spikes] = getspikes(w, X, str2double(app.FrequencyDropDown.Value));

                        if length(spikes) > 10 % If the number of peaks is low, skip the second algorithm
                            ISI = diff(spikes/str2double(app.FrequencyDropDown.Value)); % Interspike interval
                            COV(j) = std(ISI)/mean(ISI); % Coefficient of variation
                            Wini = sum(X(:,spikes),2); % update W by summing the spikes

                            % Minimization of the CoV of discharge times (end when CoV is
                            % minimized)
                            [MUFilters(:,j), ~, COV(j)] = minimizeCOVISI(Wini, X, COV(j), str2double(app.FrequencyDropDown.Value));
                            B(:,j) = w;

                            % Calculate SIL values
                            [~, ~, SIL(j)] = calcSIL(X, MUFilters(:,j), str2double(app.FrequencyDropDown.Value));

                            % % Peel-off of the (reliable) source
                            % if SIL(j) > app.SILthresholdEditField.Value
                            %     X = peeloff(X, spikes, round(str2double(app.FrequencyDropDown.Value)), 0.025);
                            % end
                        else
                            B(:,j) = w;
                        end
                        tit = ['Ite : ' num2str(j) ' - SIL : ' num2str(round(SIL(j),2)) ' - COV : ' num2str(round(COV(j),2))];
                        waitbar((j+app.NbIterationsEditField.Value*(i-1))/(app.NbIterationsEditField.Value*app.RecordParameters.ngrid),f,tit);
                    end

                    waitbar(i/app.RecordParameters.ngrid,f,['Batch processing grid #' num2str(i) '...']);
                    % Filter out MUfilters below the SIL threshold
                    MUFilters(:,SIL < app.SILthresholdEditField.Value) = [];
                    COV(SIL < app.SILthresholdEditField.Value) = [];
                    SIL(SIL < app.SILthresholdEditField.Value) = [];
                    MUFilters(:,COV > app.COVthresholdEditField.Value) = [];
                    MUFilters = dewhiteningMatrix * MUFilters;
                    

                    if size(MUFilters,2) > 0

                        % Get the pulse train for the entire signal
                        EMGfilter = filtfilt(c,d,EMG((i-1)*str2double(app.ChpergridDropDown.Value)+1:i*str2double(app.ChpergridDropDown.Value),:)');
                        [PulseT, Distime, ~] = getPulseT(EMGfilter', app.RecordParameters.DiscardChannels{i}, MUFilters, str2double(app.FrequencyDropDown.Value));

                        % Remove duplicates
                        [~, Distime] = remduplicates(PulseT, Distime, Distime, exFactor, 0.00025, 0.3, str2double(app.FrequencyDropDown.Value));

                        % Get the decomposition parameters for the biofeedback
                        app.DecompParameters.MUFilters{i} = getMUfilters(EMGfilter', app.RecordParameters.DiscardChannels{i}, Distime);
                        [PulseT, Distime, ~] = getPulseT(EMGfilter', app.RecordParameters.DiscardChannels{i}, app.DecompParameters.MUFilters{i}, str2double(app.FrequencyDropDown.Value));
                        [app.DecompParameters.extensionfactor(i), app.DecompParameters.iReSIG{i}, app.DecompParameters.norm{i}, app.DecompParameters.centroid{i}] = getonlineparameters(EMGfilter', app.RecordParameters.DiscardChannels{i}, app.DecompParameters.MUFilters{i}, str2double(app.FrequencyDropDown.Value));

                        time = linspace(0,size(PulseT,2)/str2double(app.FrequencyDropDown.Value),size(PulseT,2));

                        for k = 1:length(Distime)
                            plot(app.UIAxes3, time, PulseT(k,:)+k+offsetMU, time(Distime{k}), PulseT(k,Distime{k})+k+offsetMU, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412], 'MarkerEdgeColor', [0.81 0.50 1.00]);
                            hold(app.UIAxes3, 'on')
                            signal.Dischargetimes{i,k} = Distime{k};
                            app.DecompParameters.DR{i}(k) = mean(1./(diff(Distime{k})/str2double(app.FrequencyDropDown.Value)));
                        end

                        offsetMU = offsetMU + length(Distime) + 1;

                        signal.EMGmask{i} = app.RecordParameters.DiscardChannels{i};
                        app.DecompParameters.EMGmask{i} = app.RecordParameters.DiscardChannels{i};
                        signal.Pulsetrain{i} = PulseT;
                        if str2double(app.ChpergridDropDown.Value) == 32
                            signal.gridname{i} = 'GR08.75MM0408';
                        else
                            signal.gridname{i} = 'GR08.75MM0808';
                        end
                        signal.SIL{i} = SIL;
                        signal.COV{i} = COV;

                        clearvars Distime PulseT B w wSIG X E D eSIG SIG maskEMG SIL COV
                    end
                end

                close(f)
                hold(app.UIAxes3, 'off')
                app.UIAxes3.XLim = [time(1) time(end)];
                app.UIAxes3.YLim = [0 offsetMU];
                xlabel(app.UIAxes3,'Time');
                ylabel(app.UIAxes3,'MUs');
                
                signal.data = EMG;
                Force = movmean(Force,(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)));
                signal.path = abs(Force)/app.RecordParameters.MVC * 100;
                signal.fsamp = str2double(app.FrequencyDropDown.Value);
                signal.muscle = app.RecordParameters.Muscles;
                signal.nChan = size(EMG,1);
                signal.ngrid = app.RecordParameters.ngrid;
                save([app.EditField_saving.Value 'Training_' num2str(app.MVCTargetEditField.Value) '.mat'], 'signal');

                for i = 1:app.RecordParameters.ngrid
                    % Update the list of the MUs for the biofeedback
                    app.XaxisdisplayedDropDown.Items = {};
                    app.YaxisdisplayedDropDown.Items = {};
                    for j = 1:size(signal.Pulsetrain{i},1)
                        app.XaxisdisplayedDropDown.Items = horzcat(app.XaxisdisplayedDropDown.Items, {['Grid_', num2str(i), '_MU_' , num2str(j)]});
                        app.YaxisdisplayedDropDown.Items = horzcat(app.YaxisdisplayedDropDown.Items, {['Grid_', num2str(i), '_MU_' , num2str(j)]});
                    end
                end
                app.XaxisdisplayedDropDown.Enable = 'on';
                app.YaxisdisplayedDropDown.Enable = 'on';

            catch e
                % In case of an error close all still active devices and clean up
                % library itself
                app.ComParameters.lib.cleanUp();

                % Update the UI for the next acquisition
                app.InitializeSAGAButton.Enable = 'on';
                app.DisconnectSAGAButton.Enable = 'off';
                app.StartVisualizationCheckEMGButton.Enable = 'off';
                app.StartVisualizationChecknoiseButton.Enable = 'off';
                app.StartButton.Enable = 'off';
                app.StartMVCButton.Enable = 'off';
                app.OffsetCheckBox.Enable = 'off';

                rethrow(e)
            end         

        end

        % Button pushed function: ImportMUfiltersButton
        function ImportMUfiltersButtonPushed(app, event)
            app.UIFigure.Visible = 'off'; 
            [filename, pathname] = uigetfile();
            app.UIFigure.Visible = 'on';
            file = load([pathname filename], 'signal', 'edition');
            
            % for each grid, import the EMG signal and the discharge times
            % to generate the filters
            for i = 1:file.signal.ngrid
                app.DecompParameters.EMGmask{i} = file.signal.EMGmask{i};
                for j = 1:size(file.edition.Pulsetrain{i},1)
                    Distime{j} = file.edition.Dischargetimes{i,j}; % Import the edited discharge times
                    app.DecompParameters.DR{i}(j) = mean(1./(diff(Distime{j})/str2double(app.FrequencyDropDown.Value)));
                end
                % Get the filters and the parameters for online
                % decomposition
                [c,d] = butter(3,10/(str2double(app.FrequencyDropDown.Value)/2),'high');
                dataFilter = filtfilt(c,d,file.signal.data((i-1)*str2double(app.ChpergridDropDown.Value)+1:i*str2double(app.ChpergridDropDown.Value),:)');
                app.DecompParameters.MUFilters{i} = getMUfilters(dataFilter', app.DecompParameters.EMGmask{i}, Distime);
                [app.DecompParameters.extensionfactor(i), app.DecompParameters.iReSIG{i}, app.DecompParameters.norm{i}, app.DecompParameters.centroid{i}] = getonlineparameters(dataFilter', app.DecompParameters.EMGmask{i}, app.DecompParameters.MUFilters{i}, str2double(app.FrequencyDropDown.Value));
                clearvars Distime
            end

            % Update the list of the MUs for the biofeedback
            app.XaxisdisplayedDropDown.Items = {};
            app.YaxisdisplayedDropDown.Items = {};
            for i = 1:file.signal.ngrid
                for j = 1:size(file.edition.Pulsetrain{i},1)
                    app.XaxisdisplayedDropDown.Items = horzcat(app.XaxisdisplayedDropDown.Items, {['Grid_', num2str(i), '_MU_' , num2str(j)]});
                    app.YaxisdisplayedDropDown.Items = horzcat(app.YaxisdisplayedDropDown.Items, {['Grid_', num2str(i), '_MU_' , num2str(j)]});
                end
            end
            app.XaxisdisplayedDropDown.Enable = 'on';
            app.YaxisdisplayedDropDown.Enable = 'on';
        end

        % Value changed function: NbiterationsEditField
        function NbiterationsEditFieldValueChanged(app, event)
            fsamp = str2double(app.FrequencyDropDown.Value);
            app.OnlineParameters.durationonline = (app.DurationplateauEditField.Value * fsamp + 2*app.DurationrampEditField.Value*fsamp + 2*app.DurationrestEditField.Value*fsamp) * app.NbiterationsEditField.Value;
            time = linspace(0,app.OnlineParameters.durationonline/fsamp,app.OnlineParameters.durationonline);
            rest = zeros(1,app.DurationrestEditField.Value*fsamp);
            rampup = linspace(0,app.MVCtargetEditField.Value,app.DurationrampEditField.Value*fsamp);
            rampdown = linspace(app.MVCtargetEditField.Value,0,app.DurationrampEditField.Value*fsamp);
            plateau = ones(1,app.DurationplateauEditField.Value * fsamp) * app.MVCtargetEditField.Value;
            app.OnlineParameters.targetonline = [];
            for i = 1:app.NbiterationsEditField.Value
                app.OnlineParameters.targetonline = [app.OnlineParameters.targetonline rest rampup plateau rampdown rest];
            end
            plot(app.UIAxes2,time,app.OnlineParameters.targetonline,'Linewidth', 2, 'Color', [0.81 0.50 1.00]);
            app.UIAxes2.XLim = [-0.01 app.OnlineParameters.durationonline/fsamp];
            app.UIAxes2.YLim = [-0.01 app.MVCtargetEditField.Value + 1];
            app.UIAxes2.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes2.YColor = [0.9412 0.9412 0.9412];
            xlabel(app.UIAxes2,'Time');
            ylabel(app.UIAxes2,'Relative force');
        end

        % Value changed function: DurationrestEditField
        function DurationrestEditFieldValueChanged(app, event)
            fsamp = str2double(app.FrequencyDropDown.Value);
            app.OnlineParameters.durationonline = (app.DurationplateauEditField.Value * fsamp + 2*app.DurationrampEditField.Value*fsamp + 2*app.DurationrestEditField.Value*fsamp) * app.NbiterationsEditField.Value;
            time = linspace(0,app.OnlineParameters.durationonline/fsamp,app.OnlineParameters.durationonline);
            rest = zeros(1,app.DurationrestEditField.Value*fsamp);
            rampup = linspace(0,app.MVCtargetEditField.Value,app.DurationrampEditField.Value*fsamp);
            rampdown = linspace(app.MVCtargetEditField.Value,0,app.DurationrampEditField.Value*fsamp);
            plateau = ones(1,app.DurationplateauEditField.Value * fsamp) * app.MVCtargetEditField.Value;
            app.OnlineParameters.targetonline = [];
            for i = 1:app.NbiterationsEditField.Value
                app.OnlineParameters.targetonline = [app.OnlineParameters.targetonline rest rampup plateau rampdown rest];
            end
            plot(app.UIAxes2,time,app.OnlineParameters.targetonline,'Linewidth', 2, 'Color', [0.81 0.50 1.00]);
            app.UIAxes2.XLim = [-0.01 app.OnlineParameters.durationonline/fsamp];
            app.UIAxes2.YLim = [-0.01 app.MVCtargetEditField.Value + 1];  
            app.UIAxes2.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes2.YColor = [0.9412 0.9412 0.9412];
            xlabel(app.UIAxes2,'Time');
            ylabel(app.UIAxes2,'Relative force');
        end

        % Value changed function: DurationplateauEditField
        function DurationplateauEditFieldValueChanged(app, event)
            fsamp = str2double(app.FrequencyDropDown.Value);
            app.OnlineParameters.durationonline = (app.DurationplateauEditField.Value * fsamp + 2*app.DurationrampEditField.Value*fsamp + 2*app.DurationrestEditField.Value*fsamp) * app.NbiterationsEditField.Value;
            time = linspace(0,app.OnlineParameters.durationonline/fsamp,app.OnlineParameters.durationonline);
            rest = zeros(1,app.DurationrestEditField.Value*fsamp);
            rampup = linspace(0,app.MVCtargetEditField.Value,app.DurationrampEditField.Value*fsamp);
            rampdown = linspace(app.MVCtargetEditField.Value,0,app.DurationrampEditField.Value*fsamp);
            plateau = ones(1,app.DurationplateauEditField.Value * fsamp) * app.MVCtargetEditField.Value;
            app.OnlineParameters.targetonline = [];
            for i = 1:app.NbiterationsEditField.Value
                app.OnlineParameters.targetonline = [app.OnlineParameters.targetonline rest rampup plateau rampdown rest];
            end
            plot(app.UIAxes2,time,app.OnlineParameters.targetonline,'Linewidth', 2, 'Color', [0.81 0.50 1.00]);
            app.UIAxes2.XLim = [-0.01 app.OnlineParameters.durationonline/fsamp];
            app.UIAxes2.YLim = [-0.01 app.MVCtargetEditField.Value + 1];
            app.UIAxes2.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes2.YColor = [0.9412 0.9412 0.9412];
            xlabel(app.UIAxes2,'Time');
            ylabel(app.UIAxes2,'Relative force');
        end

        % Value changed function: DurationrampEditField
        function DurationrampEditFieldValueChanged(app, event)
            fsamp = str2double(app.FrequencyDropDown.Value);
            app.OnlineParameters.durationonline = (app.DurationplateauEditField.Value * fsamp + 2*app.DurationrampEditField.Value*fsamp + 2*app.DurationrestEditField.Value*fsamp) * app.NbiterationsEditField.Value;
            time = linspace(0,app.OnlineParameters.durationonline/fsamp,app.OnlineParameters.durationonline);
            rest = zeros(1,app.DurationrestEditField.Value*fsamp);
            rampup = linspace(0,app.MVCtargetEditField.Value,app.DurationrampEditField.Value*fsamp);
            rampdown = linspace(app.MVCtargetEditField.Value,0,app.DurationrampEditField.Value*fsamp);
            plateau = ones(1,app.DurationplateauEditField.Value * fsamp) * app.MVCtargetEditField.Value;
            app.OnlineParameters.targetonline = [];
            for i = 1:app.NbiterationsEditField.Value
                app.OnlineParameters.targetonline = [app.OnlineParameters.targetonline rest rampup plateau rampdown rest];
            end
            plot(app.UIAxes2,time,app.OnlineParameters.targetonline,'Linewidth', 2, 'Color', [0.81 0.50 1.00]);
            app.UIAxes2.XLim = [-0.01 app.OnlineParameters.durationonline/fsamp];
            app.UIAxes2.YLim = [-0.01 app.MVCtargetEditField.Value + 1];
            app.UIAxes2.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes2.YColor = [0.9412 0.9412 0.9412];
            xlabel(app.UIAxes2,'Time');
            ylabel(app.UIAxes2,'Relative force');
        end

        % Value changed function: MVCtargetEditField
        function MVCtargetEditFieldValueChanged(app, event)
            fsamp = str2double(app.FrequencyDropDown.Value);
            app.OnlineParameters.durationonline = (app.DurationplateauEditField.Value * fsamp + 2 * app.DurationrampEditField.Value * fsamp + 2*app.DurationrestEditField.Value*fsamp) * app.NbiterationsEditField.Value;
            app.NbiterationsEditField.Value
            time = linspace(0,app.OnlineParameters.durationonline/fsamp,app.OnlineParameters.durationonline);
            rest = zeros(1,app.DurationrestEditField.Value * fsamp);
            rampup = linspace(0,app.MVCtargetEditField.Value,app.DurationrampEditField.Value * fsamp);
            rampdown = linspace(app.MVCtargetEditField.Value,0,app.DurationrampEditField.Value * fsamp);
            plateau = ones(1,app.DurationplateauEditField.Value * fsamp) * app.MVCtargetEditField.Value;
            app.OnlineParameters.targetonline = [];
            for i = 1:app.NbiterationsEditField.Value
                app.OnlineParameters.targetonline = [app.OnlineParameters.targetonline rest rampup plateau rampdown rest];
            end
            plot(app.UIAxes2,time,app.OnlineParameters.targetonline,'Linewidth', 2, 'Color', [0.81 0.50 1.00]);
            app.UIAxes2.XLim = [-0.01 app.OnlineParameters.durationonline/fsamp];
            app.UIAxes2.YLim = [-0.01 app.MVCtargetEditField.Value + 1];
            app.UIAxes2.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes2.YColor = [0.9412 0.9412 0.9412];
            xlabel(app.UIAxes2,'Time');
            ylabel(app.UIAxes2,'Relative force');
        end

        % Button pushed function: StartRasterPlotwithMUsButton
        function StartRasterPlotwithMUsButtonPushed(app, event)
            C1 = strsplit(app.XaxisdisplayedDropDown.Value,'_');
            idxmuscle1 = str2double(C1{2});

            nwin = app.OnlineParameters.durationonline;
            nwinbuffmax = 5*str2double(app.RefreshrateDropDown.Value);
            k = 1;
            i = 1;
            [c,d] = butter(3,10/(str2double(app.FrequencyDropDown.Value)/2),'high');
            
            % Pre allocate empty matrices
            Time = linspace(0,nwin/str2double(app.FrequencyDropDown.Value),nwin);
            EMG = zeros(str2double(app.ChpergridDropDown.Value),nwin);
            EMGsave = zeros(app.RecordParameters.ngrid*str2double(app.ChpergridDropDown.Value),nwin);
            Force = NaN(1,nwin);
            EMGtmp{1} = zeros(str2double(app.ChpergridDropDown.Value),(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)));
            esample2{1} = zeros((str2double(app.ChpergridDropDown.Value)-sum(app.DecompParameters.EMGmask{idxmuscle1}))*app.DecompParameters.extensionfactor(idxmuscle1),app.DecompParameters.extensionfactor(idxmuscle1)-1);
            Distime{1} = zeros(nwin, size(app.DecompParameters.MUFilters{idxmuscle1},2));
            signal.Pulsetrain{1} = zeros(size(app.DecompParameters.MUFilters{idxmuscle1},2), nwin);
            noise_centroids{1} = ones((str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)),size(app.DecompParameters.MUFilters{idxmuscle1},2)) .* (app.DecompParameters.centroid{idxmuscle1}(:,1)');
            spike_centroids{1} = ones((str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)),size(app.DecompParameters.MUFilters{idxmuscle1},2)) .* (app.DecompParameters.centroid{idxmuscle1}(:,2)');
            MUfilt{1} = app.DecompParameters.MUFilters{idxmuscle1}' * app.DecompParameters.iReSIG{idxmuscle1};
            MUoffset = 1:1:size(app.DecompParameters.MUFilters{idxmuscle1},2);
            forcefb = zeros(1,nwin/(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)));
            feedback = zeros(1,nwin/(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)));

            % Set the properties of the axes for data viz
            plot(app.UIAxes4, Time(1:str2double(app.FrequencyDropDown.Value)*5), Distime{1}(1:str2double(app.FrequencyDropDown.Value)*5,:).*MUoffset, '|', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.56 0.62 0.85])
            plot(app.UIAxes5, 1, forcefb(k),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
            line(app.UIAxes5,[0 2], [feedback(k)*0.95 feedback(k)*0.95],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
            line(app.UIAxes5,[0 2], [feedback(k)*1.05 feedback(k)*1.05],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])          
            app.UIAxes4.XLim = [Time(1) Time(str2double(app.FrequencyDropDown.Value)*5)];
            app.UIAxes4.YLim = [0.5 size(app.DecompParameters.MUFilters{idxmuscle1},2)+0.5];
            app.UIAxes5.XLim = [0 2];
            app.UIAxes5.YLim = [0 max(app.OnlineParameters.targetonline)*1.25];
            app.UIAxes4.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes4.YColor = [0.9412 0.9412 0.9412];
            app.UIAxes5.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes5.YColor = [0.9412 0.9412 0.9412];
            xlabel(app.UIAxes4,'Time');
            ylabel(app.UIAxes4,'MU Discharge times');

            
            % Code within the try-catch to ensure that all devices are stopped and
            % closed properly in case of a failure.
            try

                app.ComParameters.device.start();
                while i <= nwin - str2double(app.FrequencyDropDown.Value)*5
                    [samples, num_sets] = app.ComParameters.device.sample();
                    if num_sets > 0
                        EMG(:,i:i+num_sets-1) = samples(app.ComParameters.gridchannels((idxmuscle1-1)*str2double(app.ChpergridDropDown.Value)+1:idxmuscle1*str2double(app.ChpergridDropDown.Value)), :);
                        EMGsave(:,i:i+num_sets-1) = samples(app.ComParameters.gridchannels, :);
                        Force(:,i:i+num_sets-1) = samples(app.ComParameters.gridchannels(end)+4+str2double(app.ForcechDropDown.Value),:) - app.RecordParameters.offsetforce;


                        if k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)) <= i+num_sets-1
                            forcefb(k) = mean(abs(Force((k-1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)))));
                            feedback(k) = mean(app.OnlineParameters.targetonline((k-1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))));

                            EMGtmp{1} = filtfilt(c,d,EMG(app.DecompParameters.EMGmask{idxmuscle1}==0, (k-1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)))');

                            [signal.Pulsetrain{1}(:,(k-1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))), Distime{1}((k-1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)), :), esample2{1}] = getspikesonline(EMGtmp{1}', app.DecompParameters.extensionfactor(idxmuscle1), esample2{1}, MUfilt{1}, app.DecompParameters.norm{idxmuscle1}, noise_centroids{1}, spike_centroids{1}, (str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)), str2double(app.FrequencyDropDown.Value));

                            if k < nwinbuffmax
                                plot(app.UIAxes4, Time(1:str2double(app.FrequencyDropDown.Value)*5), Distime{1}(1:str2double(app.FrequencyDropDown.Value)*5,:).*MUoffset, '|', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.56 0.62 0.85])
                                app.UIAxes4.XLim = [Time(1) Time(str2double(app.FrequencyDropDown.Value)*5)];
                                plot(app.UIAxes5, 1, forcefb(k)/app.RecordParameters.MVC * 100,'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
                                line(app.UIAxes5,[0 2], [feedback(k)*0.95 feedback(k)*0.95],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                                line(app.UIAxes5,[0 2], [feedback(k)*1.05 feedback(k)*1.05],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                            else
                                plot(app.UIAxes4, Time((k-1)*str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)+1-str2double(app.FrequencyDropDown.Value)*2.5:str2double(app.FrequencyDropDown.Value)*2.5+(k-1)*str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)), Distime{1}((k-1)*str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)+1-str2double(app.FrequencyDropDown.Value)*2.5:str2double(app.FrequencyDropDown.Value)*2.5+(k-1)*str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value),:).*MUoffset, '|', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.56 0.62 0.85])
                                app.UIAxes4.XLim = [Time((k-1)*str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)+1-str2double(app.FrequencyDropDown.Value)*2.5) Time(str2double(app.FrequencyDropDown.Value)*2.5+(k-1)*str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))];
                                plot(app.UIAxes5, 1, forcefb(k)/app.RecordParameters.MVC * 100,'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
                                line(app.UIAxes5,[0 2], [feedback(k)*0.95 feedback(k)*0.95],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                                line(app.UIAxes5,[0 2], [feedback(k)*1.05 feedback(k)*1.05],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                            end
                            app.UIAxes5.YLim = [0 max(app.OnlineParameters.targetonline)*1.25];
                            app.UIAxes4.YLim = [0.5 size(app.DecompParameters.MUFilters{idxmuscle1},2)+0.5];
                            drawnow limitrate
                            k = k+1;
                        end
                        i = i + num_sets;
                    end
                end
                % Stop sampling on the device
                app.ComParameters.device.stop();

                % Update device configuration
                app.ComParameters.device.setDeviceConfig(app.ComParameters.deviceConfig);


                % Save the data
                signal.data = EMGsave;
                signal.target = app.OnlineParameters.targetonline;
                Force = movmean(Force,str2double(app.RefreshrateDropDown.Value));
                signal.path = Force;
                signal.fsamp = str2double(app.FrequencyDropDown.Value);
                signal.muscle = app.RecordParameters.Muscles{idxmuscle1};
                signal.nChan = size(EMG,1);
                signal.ngrid = app.RecordParameters.ngrid;
                signal.EMGmask{1} = app.DecompParameters.EMGmask{idxmuscle1};
                if str2double(app.ChpergridDropDown.Value) == 32
                    signal.gridname{i} = 'GR08.75MM0408';
                else
                    signal.gridname{i} = 'GR08.75MM0808';
                end
                signal.feedback{1} = 'Raster plot';
                for k = 1:size(Distime{1},2)
                    signal.Dischargetimes{k} = find(Distime{1}(:,k) > 0);
                end
                save([app.EditField_saving.Value 'Online ' replace(datestr(datetime('now')),':','_') '.mat'], 'signal');

                % Update the results of the last decomposition
                app.OnlineParameters.Timeplot = linspace(0,size(signal.Pulsetrain{1},2)/str2double(app.FrequencyDropDown.Value),size(signal.Pulsetrain{1},2));
                app.OnlineParameters.Pulsetrain = [];
                app.OnlineParameters.Dischargetimes = {};
                nMU = 1;
                for i = 1:length(Distime)
                    app.OnlineParameters.Pulsetrain = [app.OnlineParameters.Pulsetrain; signal.Pulsetrain{i}];
                    for j = 1:size(Distime{i},2)
                        app.OnlineParameters.Dischargetimes{nMU} =  signal.Dischargetimes{i,j};
                        nMU = nMU + 1;
                    end
                end
                clearvars Distime

                app.DisplaythePulsetrainsButton.Enable = 'on';

                % % Updates the parameters of the decomposition
                % EMG = EMG(:,str2double(app.FrequencyDropDown.Value):end);
                % [~, Distime, ~] = getPulseT(EMG, app.RecordParameters.DiscardChannels{idxmuscle1}, app.DecompParameters.MUFilters{idxmuscle1}, str2double(app.FrequencyDropDown.Value));
                % app.DecompParameters.MUFilters{idxmuscle1} = getMUfilters(EMG, app.RecordParameters.DiscardChannels{idxmuscle1}, Distime);
                % [app.DecompParameters.extensionfactor(idxmuscle1), app.DecompParameters.iReSIG{idxmuscle1}, app.DecompParameters.norm{idxmuscle1}, app.DecompParameters.centroid{idxmuscle1}] = getonlineparameters(EMG, app.RecordParameters.DiscardChannels{idxmuscle1}, app.DecompParameters.MUFilters{idxmuscle1}, str2double(app.FrequencyDropDown.Value));

            catch
                % In case of an error close all still active devices and clean up
                % library itself
                app.ComParameters.lib.cleanUp();

                % Update the UI for the next acquisition
                app.InitializeSAGAButton_2.Enable = 'on';
                app.DisconnectSAGAButton_2.Enable = 'off';
                app.StartQuadrantwithCSTsButton.Enable = 'off';
                app.StartQuadrantwithMUsButton.Enable = 'off';
                app.StartRasterPlotwithMUsButton.Enable = 'off';
                app.StartDRofalltheMUsButton.Enable = 'off';
                app.DisplaythePulsetrainsButton.Enable = 'on';
            end
        end

        % Button pushed function: StartQuadrantwithCSTsButton
        function StartQuadrantwithCSTsButtonPushed(app, event)
            C1 = strsplit(app.XaxisdisplayedDropDown.Value,'_');
            idxmuscle1 = str2double(C1{2});

            nwin = app.OnlineParameters.durationonline;
            nwinbuffmax = 5*str2double(app.RefreshrateDropDown.Value);
            k = 1;
            i = 1;
            [c,d] = butter(3,10/(str2double(app.FrequencyDropDown.Value)/2),'high');

            % Pre allocate empty matrices
            EMG = zeros(str2double(app.ChpergridDropDown.Value),nwin);
            EMGsave = zeros(app.RecordParameters.ngrid*str2double(app.ChpergridDropDown.Value),nwin);
            Force = NaN(1,nwin);
            Time = linspace(0,nwin/str2double(app.FrequencyDropDown.Value), floor(nwin*str2double(app.RefreshrateDropDown.Value)/str2double(app.FrequencyDropDown.Value)));
            EMGtmp{1} = zeros(str2double(app.ChpergridDropDown.Value),(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)));
            esample2{1} = zeros((str2double(app.ChpergridDropDown.Value)-sum(app.DecompParameters.EMGmask{idxmuscle1}))*app.DecompParameters.extensionfactor(idxmuscle1),app.DecompParameters.extensionfactor(idxmuscle1)-1);
            Distime{1} = zeros(nwin, size(app.DecompParameters.MUFilters{idxmuscle1},2));
            signal.Pulsetrain{1} = zeros(size(app.DecompParameters.MUFilters{idxmuscle1},2), nwin);
            noise_centroids{1} = ones((str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)),size(app.DecompParameters.MUFilters{idxmuscle1},2)) .* (app.DecompParameters.centroid{idxmuscle1}(:,1)');
            spike_centroids{1} = ones((str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)),size(app.DecompParameters.MUFilters{idxmuscle1},2)) .* (app.DecompParameters.centroid{idxmuscle1}(:,2)');
            MUfilt{1} = app.DecompParameters.MUFilters{idxmuscle1}' * app.DecompParameters.iReSIG{idxmuscle1};
            DR = NaN(nwin*str2double(app.RefreshrateDropDown.Value)/str2double(app.FrequencyDropDown.Value), size(app.DecompParameters.MUFilters{idxmuscle1},2));
            forcefb = zeros(1,nwin/(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)));
            feedback = zeros(1,nwin/(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)));

            % Set the properties of the axes for data viz
            idxhMU = floor(size(app.DecompParameters.MUFilters{idxmuscle1},2)/2);
            plot(app.UIAxes4, DR(k:k+7,1) , DR(k:k+7,2), DR(k,1) , DR(k,2),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
            plot(app.UIAxes5, 1, forcefb(k),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
            line(app.UIAxes5,[0 2], [feedback(k)*0.95 feedback(k)*0.95],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
            line(app.UIAxes5,[0 2], [feedback(k)*1.05 feedback(k)*1.05],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
            app.UIAxes4.XLim = [0 sum(app.DecompParameters.DR{idxmuscle1}(1:idxhMU))*1.2];
            app.UIAxes4.YLim = [0 sum(app.DecompParameters.DR{idxmuscle1}(idxhMU+1:end))*1.2];
            app.UIAxes5.XLim = [0 2];
            app.UIAxes5.YLim = [0 max(app.OnlineParameters.targetonline)*1.25];
            app.UIAxes4.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes4.YColor = [0.9412 0.9412 0.9412];
            app.UIAxes5.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes5.YColor = [0.9412 0.9412 0.9412];
            xlabel(app.UIAxes4,'Discharge rate CST1');
            ylabel(app.UIAxes4,'Discharge rate CST2');

            % Code within the try-catch to ensure that all devices are stopped and
            % closed properly in case of a failure.
            try

                app.ComParameters.device.start();
                while i <= nwin - str2double(app.FrequencyDropDown.Value)*5
                    [samples, num_sets] = app.ComParameters.device.sample();
                    if num_sets > 0
                        EMG(:,i:i+num_sets-1) = samples(app.ComParameters.gridchannels((idxmuscle1-1)*str2double(app.ChpergridDropDown.Value)+1:idxmuscle1*str2double(app.ChpergridDropDown.Value)), :);
                        EMGsave(:,i:i+num_sets-1) = samples(app.ComParameters.gridchannels, :);
                        Force(:,i:i+num_sets-1) = samples(app.ComParameters.gridchannels(end)+4+str2double(app.ForcechDropDown.Value),:) - app.RecordParameters.offsetforce;

                        if k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)) <= i+num_sets-1
                            forcefb(k) = mean(abs(Force((k-1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)))));
                            feedback(k) = mean(app.OnlineParameters.targetonline((k-1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))));

                            EMGtmp{1} = filtfilt(c,d,EMG(app.DecompParameters.EMGmask{idxmuscle1}==0, (k-1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)))');

                            [signal.Pulsetrain{1}(:,(k-1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))), Distime{1}((k-1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)), :), esample2{1}] = getspikesonline(EMGtmp{1}', app.DecompParameters.extensionfactor(idxmuscle1), esample2{1}, MUfilt{1}, app.DecompParameters.norm{idxmuscle1}, noise_centroids{1}, spike_centroids{1}, (str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)), str2double(app.FrequencyDropDown.Value));

                            if k < nwinbuffmax
                                DR(k,1) = sum(Distime{1}(1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)), 1:idxhMU) > 0, 'all');
                                DR(k,2) = sum(Distime{1}(1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)), idxhMU+1:end) > 0, 'all');
                                plot(app.UIAxes4, DR(1:10*str2double(app.RefreshrateDropDown.Value),1), DR(1:10*str2double(app.RefreshrateDropDown.Value),2), DR(k,1) , DR(k,2),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.56 0.62 0.85])
                                plot(app.UIAxes5, 1, forcefb(k)/app.RecordParameters.MVC * 100,'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
                                line(app.UIAxes5,[0 2], [feedback(k)*0.95 feedback(k)*0.95],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                                line(app.UIAxes5,[0 2], [feedback(k)*1.05 feedback(k)*1.05],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                            else
                                DR(k,1) = sum(Distime{1}((k-str2double(app.RefreshrateDropDown.Value)+1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)), 1:idxhMU) > 0, 'all');
                                DR(k,2) = sum(Distime{1}((k-str2double(app.RefreshrateDropDown.Value)+1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)), idxhMU+1:end) > 0, 'all');
                                DR(k,:) = mean(DR(k-(app.SmoothingEditField.Value-1):k,:), 1);
                                plot(app.UIAxes4, DR(k-5*str2double(app.RefreshrateDropDown.Value)+1:k+5*str2double(app.RefreshrateDropDown.Value),1), DR(k-5*str2double(app.RefreshrateDropDown.Value)+1:k+5*str2double(app.RefreshrateDropDown.Value),2), DR(k,1) , DR(k,2),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.56 0.62 0.85])
                                plot(app.UIAxes5, 1, forcefb(k)/app.RecordParameters.MVC * 100,'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
                                line(app.UIAxes5,[0 2], [feedback(k)*0.95 feedback(k)*0.95],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                                line(app.UIAxes5,[0 2], [feedback(k)*1.05 feedback(k)*1.05],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                            end
                            app.UIAxes4.XLim = [0 sum(app.DecompParameters.DR{idxmuscle1}(1:idxhMU))*1.2];
                            app.UIAxes4.YLim = [0 sum(app.DecompParameters.DR{idxmuscle1}(idxhMU+1:end))*1.2];
                            app.UIAxes5.YLim = [0 max(app.OnlineParameters.targetonline)*1.25];

                            drawnow limitrate
                            k = k+1;
                        end
                        i = i + num_sets;
                    end
                end

                % Stop sampling on the device
                app.ComParameters.device.stop();

                % Update device configuration
                app.ComParameters.device.setDeviceConfig(app.ComParameters.deviceConfig);

                % Save the data
                signal.data = EMGsave;
                signal.target = app.OnlineParameters.targetonline;
                Force = movmean(Force,str2double(app.RefreshrateDropDown.Value));
                signal.path = Force;
                signal.fsamp = str2double(app.FrequencyDropDown.Value);
                signal.muscle = app.RecordParameters.Muscles{idxmuscle1};
                signal.nChan = size(EMGsave,1);
                signal.ngrid = app.RecordParameters.ngrid;
                signal.EMGmask{1} = app.DecompParameters.EMGmask{idxmuscle1};
                if str2double(app.ChpergridDropDown.Value) == 32
                    signal.gridname{i} = 'GR08.75MM0408';
                else
                    signal.gridname{i} = 'GR08.75MM0808';
                end
                signal.feedback{1} = 'Quadrant_CST';
                for k = 1:size(Distime{1},2)
                    signal.Dischargetimes{k} = find(Distime{1}(:,k) > 0);
                end
                save([app.EditField_saving.Value 'Online ' replace(datestr(datetime('now')),':','_') '.mat'], 'signal');

                % Update the results of the last decomposition
                app.OnlineParameters.Timeplot = linspace(0,size(signal.Pulsetrain{1},2)/str2double(app.FrequencyDropDown.Value),size(signal.Pulsetrain{1},2));
                app.OnlineParameters.Pulsetrain = [];
                app.OnlineParameters.Dischargetimes = {};
                nMU = 1;
                for i = 1:length(Distime)
                    app.OnlineParameters.Pulsetrain = [app.OnlineParameters.Pulsetrain; signal.Pulsetrain{i}];
                    for j = 1:size(Distime{i},2)
                        app.OnlineParameters.Dischargetimes{nMU} =  signal.Dischargetimes{i,j};
                        nMU = nMU + 1;
                    end
                end
                clearvars Distime

                app.DisplaythePulsetrainsButton.Enable = 'on';

                % % Updates the parameters of the decomposition
                % EMG = EMG(:,str2double(app.FrequencyDropDown.Value):end);
                % [~, Distime, ~] = getPulseT(EMG, app.RecordParameters.DiscardChannels{idxmuscle1}, app.DecompParameters.MUFilters{idxmuscle1}, str2double(app.FrequencyDropDown.Value));
                % app.DecompParameters.MUFilters{idxmuscle1} = getMUfilters(EMG, app.RecordParameters.DiscardChannels{idxmuscle1}, Distime);
                % [app.DecompParameters.extensionfactor(idxmuscle1), app.DecompParameters.iReSIG{idxmuscle1}, app.DecompParameters.norm{idxmuscle1}, app.DecompParameters.centroid{idxmuscle1}] = getonlineparameters(EMG, app.RecordParameters.DiscardChannels{idxmuscle1}, app.DecompParameters.MUFilters{idxmuscle1}, str2double(app.FrequencyDropDown.Value));

            catch e
                % In case of an error close all still active devices and clean up
                % library itself
                app.ComParameters.lib.cleanUp();

                % Update the UI for the next acquisition
                app.InitializeSAGAButton_2.Enable = 'on';
                app.DisconnectSAGAButton_2.Enable = 'off';
                app.StartQuadrantwithCSTsButton.Enable = 'off';
                app.StartQuadrantwithMUsButton.Enable = 'off';
                app.StartRasterPlotwithMUsButton.Enable = 'off';
                app.StartDRofalltheMUsButton.Enable = 'off';
                app.DisplaythePulsetrainsButton.Enable = 'on';

                rethrow(e)
            end
        end

        % Button pushed function: StartQuadrantwithMUsButton
        function StartQuadrantwithMUsButtonPushed(app, event)
            C1 = strsplit(app.XaxisdisplayedDropDown.Value,'_');
            C2 = strsplit(app.YaxisdisplayedDropDown.Value,'_');
            idxmuscle1 = str2double(C1{2});
            idxmuscle2 = str2double(C2{2});
            idxmu1 = str2double(C1{4});
            idxmu2 = str2double(C2{4});

            nwin = app.OnlineParameters.durationonline;
            nwinbuffmax = 5*str2double(app.RefreshrateDropDown.Value);
            k = 1;
            i = 1;
            [c,d] = butter(3,10/(str2double(app.FrequencyDropDown.Value)/2),'high');

            % Pre allocate empty matrices
            Force = NaN(1,nwin);
            EMGsave = zeros(app.RecordParameters.ngrid*str2double(app.ChpergridDropDown.Value),nwin);

            EMG{1} = zeros(str2double(app.ChpergridDropDown.Value),nwin);
            EMGtmp{1} = zeros(str2double(app.ChpergridDropDown.Value),(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)));
            esample2{1} = zeros((str2double(app.ChpergridDropDown.Value)-sum(app.DecompParameters.EMGmask{idxmuscle1}))*app.DecompParameters.extensionfactor(idxmuscle1),app.DecompParameters.extensionfactor(idxmuscle1)-1);
            Distime{1} = zeros(nwin, size(app.DecompParameters.MUFilters{idxmuscle1},2));
            signal.Pulsetrain{1} = zeros(size(app.DecompParameters.MUFilters{idxmuscle1},2), nwin);
            noise_centroids{1} = ones((str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)),size(app.DecompParameters.MUFilters{idxmuscle1},2)) .* (app.DecompParameters.centroid{idxmuscle1}(:,1)');
            spike_centroids{1} = ones((str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)),size(app.DecompParameters.MUFilters{idxmuscle1},2)) .* (app.DecompParameters.centroid{idxmuscle1}(:,2)');
            MUfilt{1} = app.DecompParameters.MUFilters{idxmuscle1}' * app.DecompParameters.iReSIG{idxmuscle1};

            EMG{2} = zeros(str2double(app.ChpergridDropDown.Value),nwin);
            EMGtmp{2} = zeros(str2double(app.ChpergridDropDown.Value),(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)));
            esample2{2} = zeros((str2double(app.ChpergridDropDown.Value)-sum(app.DecompParameters.EMGmask{idxmuscle2}))*app.DecompParameters.extensionfactor(idxmuscle2),app.DecompParameters.extensionfactor(idxmuscle2)-1);
            Distime{2} = zeros(nwin, size(app.DecompParameters.MUFilters{idxmuscle2},2));
            signal.Pulsetrain{2} = zeros(size(app.DecompParameters.MUFilters{idxmuscle2},2), nwin);
            noise_centroids{2} = ones((str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)),size(app.DecompParameters.MUFilters{idxmuscle2},2)) .* (app.DecompParameters.centroid{idxmuscle2}(:,1)');
            spike_centroids{2} = ones((str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)),size(app.DecompParameters.MUFilters{idxmuscle2},2)) .* (app.DecompParameters.centroid{idxmuscle2}(:,2)');
            MUfilt{2} = app.DecompParameters.MUFilters{idxmuscle2}' * app.DecompParameters.iReSIG{idxmuscle2};

            DR{1} = NaN(nwin*str2double(app.RefreshrateDropDown.Value)/str2double(app.FrequencyDropDown.Value), size(app.DecompParameters.MUFilters{idxmuscle1},2));
            DR{2} = NaN(nwin*str2double(app.RefreshrateDropDown.Value)/str2double(app.FrequencyDropDown.Value), size(app.DecompParameters.MUFilters{idxmuscle2},2));
            forcefb = zeros(1,nwin/(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)));
            feedback = zeros(1,nwin/(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)));

            % Set the properties of the axes for data viz         
            plot(app.UIAxes4, DR{1}(k:k+7,idxmu1) , DR{2}(k:k+7,idxmu2), DR{1}(k,idxmu1) , DR{2}(k,idxmu2),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.56 0.62 0.85])
            plot(app.UIAxes5, 1, forcefb(k),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
            line(app.UIAxes5,[0 2], [feedback(k)*0.95 feedback(k)*0.95],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
            line(app.UIAxes5,[0 2], [feedback(k)*1.05 feedback(k)*1.05],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
            app.UIAxes4.XLim = [0 app.DecompParameters.DR{idxmuscle1}(idxmu1)*1.2];
            app.UIAxes4.YLim = [0 app.DecompParameters.DR{idxmuscle2}(idxmu2)*1.2];
            app.UIAxes5.XLim = [0 2];
            app.UIAxes5.YLim = [0 max(app.OnlineParameters.targetonline)*1.25];
            app.UIAxes4.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes4.YColor = [0.9412 0.9412 0.9412];
            app.UIAxes5.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes5.YColor = [0.9412 0.9412 0.9412];
            xlabel(app.UIAxes4,'Discharge rate MU1');
            ylabel(app.UIAxes4,'Discharge rate MU2');

            % Code within the try-catch to ensure that all devices are stopped and
            % closed properly in case of a failure.
            try

                app.ComParameters.device.start();
                while i <= nwin - str2double(app.FrequencyDropDown.Value)*5
                    [samples, num_sets] = app.ComParameters.device.sample();
                    if num_sets > 0
                        EMG{1}(:,i:i+num_sets-1) = samples(app.ComParameters.gridchannels((idxmuscle1-1)*str2double(app.ChpergridDropDown.Value)+1:idxmuscle1*str2double(app.ChpergridDropDown.Value)), :);
                        EMG{2}(:,i:i+num_sets-1) = samples(app.ComParameters.gridchannels((idxmuscle2-1)*str2double(app.ChpergridDropDown.Value)+1:idxmuscle2*str2double(app.ChpergridDropDown.Value)), :);
                        EMGsave(:,i:i+num_sets-1) = samples(app.ComParameters.gridchannels, :);
                        Force(:,i:i+num_sets-1) = samples(app.ComParameters.gridchannels(end)+4+str2double(app.ForcechDropDown.Value),:) - app.RecordParameters.offsetforce;

                        if k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)) <= i+num_sets-1
                            forcefb(k) = mean(abs(Force((k-1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)))));
                            feedback(k) = mean(app.OnlineParameters.targetonline((k-1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))));

                            EMGtmp{1} = filtfilt(c,d,EMG{1}(app.DecompParameters.EMGmask{idxmuscle1}==0, (k-1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)))');
                            [signal.Pulsetrain{1}(:,(k-1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))), Distime{1}((k-1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)), :), esample2{1}] = getspikesonline(EMGtmp{1}', app.DecompParameters.extensionfactor(idxmuscle1), esample2{1}, MUfilt{1}, app.DecompParameters.norm{idxmuscle1}, noise_centroids{1}, spike_centroids{1}, (str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)), str2double(app.FrequencyDropDown.Value));

                            EMGtmp{2} = filtfilt(c,d,EMG{2}(app.DecompParameters.EMGmask{idxmuscle2}==0, (k-1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)))');
                            [signal.Pulsetrain{2}(:,(k-1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))), Distime{2}((k-1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)), :), esample2{2}] = getspikesonline(EMGtmp{2}', app.DecompParameters.extensionfactor(idxmuscle2), esample2{2}, MUfilt{2}, app.DecompParameters.norm{idxmuscle2}, noise_centroids{2}, spike_centroids{2}, (str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)), str2double(app.FrequencyDropDown.Value));

                            if k < nwinbuffmax
                                DR{1}(k,:) = sum(Distime{1}(1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)), :) > 0, 1);
                                DR{2}(k,:) = sum(Distime{2}(1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)), :) > 0, 1);
                                plot(app.UIAxes4, DR{1}(1:10*str2double(app.RefreshrateDropDown.Value),idxmu1) , DR{2}(1:10*str2double(app.RefreshrateDropDown.Value),idxmu2), DR{1}(k,idxmu1) , DR{2}(k,idxmu2),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.56 0.62 0.85])
                                plot(app.UIAxes5, 1, forcefb(k)/app.RecordParameters.MVC * 100,'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
                                line(app.UIAxes5,[0 2], [feedback(k)*0.95 feedback(k)*0.95],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                                line(app.UIAxes5,[0 2], [feedback(k)*1.05 feedback(k)*1.05],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                            else
                                DR{1}(k,:) = sum(Distime{1}((k-str2double(app.RefreshrateDropDown.Value)+1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)), :) > 0, 1);
                                DR{2}(k,:) = sum(Distime{2}((k-str2double(app.RefreshrateDropDown.Value)+1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)), :) > 0, 1);
                                DR{1}(k,:) = mean(DR{1}(k-(app.SmoothingEditField.Value-1):k,:), 1);
                                DR{2}(k,:) = mean(DR{2}(k-(app.SmoothingEditField.Value-1):k,:), 1);
                                plot(app.UIAxes4, DR{1}(k-5*str2double(app.RefreshrateDropDown.Value)+1:k+5*str2double(app.RefreshrateDropDown.Value),idxmu1) , DR{2}(k-5*str2double(app.RefreshrateDropDown.Value)+1:k+5*str2double(app.RefreshrateDropDown.Value),idxmu2), DR{1}(k,idxmu1) , DR{2}(k,idxmu2),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.56 0.62 0.85])
                                plot(app.UIAxes5, 1, forcefb(k)/app.RecordParameters.MVC * 100,'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
                                line(app.UIAxes5,[0 2], [feedback(k)*0.95 feedback(k)*0.95],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                                line(app.UIAxes5,[0 2], [feedback(k)*1.05 feedback(k)*1.05],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                            end
                            app.UIAxes4.XLim = [0 sum(app.DecompParameters.DR{idxmuscle1}(idxmu1))*1.2];
                            app.UIAxes4.YLim = [0 sum(app.DecompParameters.DR{idxmuscle2}(idxmu2))*1.2];
                            app.UIAxes5.YLim = [0 max(app.OnlineParameters.targetonline)*1.25];

                            drawnow limitrate
                            k = k+1;
                        end
                        i = i + num_sets;
                    end
                end
                % Stop sampling on the device
                app.ComParameters.device.stop();

                % Update device configuration
                app.ComParameters.device.setDeviceConfig(app.ComParameters.deviceConfig);

                % Save the data
                signal.data = EMGsave;
                signal.target = app.OnlineParameters.targetonline;
                Force = movmean(Force,str2double(app.RefreshrateDropDown.Value));
                signal.path = Force;
                signal.fsamp = str2double(app.FrequencyDropDown.Value);
                signal.muscle = app.RecordParameters.Muscles;
                signal.nChan = size(EMGsave,1);
                signal.ngrid = app.RecordParameters.ngrid;
                signal.EMGmask{1} = app.DecompParameters.EMGmask{idxmuscle1};
                signal.EMGmask{2} = app.DecompParameters.EMGmask{idxmuscle2};
                if str2double(app.ChpergridDropDown.Value) == 32
                    signal.gridname{1} = 'GR08.75MM0408';
                    signal.gridname{2} = 'GR08.75MM0408';
                else
                    signal.gridname{1} = 'GR08.75MM0808';
                    signal.gridname{2} = 'GR08.75MM0408';
                end
                signal.feedback{1} = 'Quadrant_MU';
                for i = 1:2
                    for k = 1:size(Distime{i},2)
                        signal.Dischargetimes{i,k} = find(Distime{i}(:,k) > 0);
                    end
                end
                save([app.EditField_saving.Value 'Online ' replace(datestr(datetime('now')),':','_') '.mat'], 'signal');

                % Update the results of the last decomposition
                app.OnlineParameters.Timeplot = linspace(0,size(signal.Pulsetrain{1},2)/str2double(app.FrequencyDropDown.Value),size(signal.Pulsetrain{1},2));
                app.OnlineParameters.Pulsetrain = [];
                app.OnlineParameters.Dischargetimes = {};
                nMU = 1;
                for i = 1:length(Distime)
                    app.OnlineParameters.Pulsetrain = [app.OnlineParameters.Pulsetrain; signal.Pulsetrain{i}];
                    for j = 1:size(Distime{i},2)
                        app.OnlineParameters.Dischargetimes{nMU} =  signal.Dischargetimes{i,j};
                        nMU = nMU + 1;
                    end
                end
                clearvars Distime

                app.DisplaythePulsetrainsButton.Enable = 'on';

                % % Updates the parameters of the decomposition
                % EMG = EMG(:,str2double(app.FrequencyDropDown.Value):end);
                % [~, Distime, ~] = getPulseT(EMG((idxmuscle1-1)*64+1:idxmuscle1*64,:), app.RecordParameters.DiscardChannels{idxmuscle1}, app.DecompParameters.MUFilters{idxmuscle1}, str2double(app.FrequencyDropDown.Value));
                % app.DecompParameters.MUFilters{idxmuscle1} = getMUfilters(EMG((idxmuscle1-1)*64+1:idxmuscle1*64,:), app.RecordParameters.DiscardChannels{idxmuscle1}, Distime);
                % [app.DecompParameters.extensionfactor(idxmuscle1), app.DecompParameters.iReSIG{idxmuscle1}, app.DecompParameters.norm{idxmuscle1}, app.DecompParameters.centroid{idxmuscle1}] = getonlineparameters(EMG((idxmuscle1-1)*64+1:idxmuscle1*64,:), app.RecordParameters.DiscardChannels{idxmuscle1}, app.DecompParameters.MUFilters{idxmuscle1}, str2double(app.FrequencyDropDown.Value));
                % [~, Distime, ~] = getPulseT(EMG((idxmuscle2-1)*64+1:idxmuscle2*64,:), app.RecordParameters.DiscardChannels{idxmuscle2}, app.DecompParameters.MUFilters{idxmuscle2}, str2double(app.FrequencyDropDown.Value));
                % app.DecompParameters.MUFilters{idxmuscle2} = getMUfilters(EMG((idxmuscle2-1)*64+1:idxmuscle2*64,:), app.RecordParameters.DiscardChannels{idxmuscle2}, Distime);
                % [app.DecompParameters.extensionfactor(idxmuscle2), app.DecompParameters.iReSIG{idxmuscle2}, app.DecompParameters.norm{idxmuscle2}, app.DecompParameters.centroid{idxmuscle2}] = getonlineparameters(EMG((idxmuscle2-1)*64+1:idxmuscle2*64,:), app.RecordParameters.DiscardChannels{idxmuscle2}, app.DecompParameters.MUFilters{idxmuscle2}, str2double(app.FrequencyDropDown.Value));

            catch e
                % In case of an error close all still active devices and clean up
                % library itself
                app.ComParameters.lib.cleanUp();

                % Update the UI for the next acquisition
                app.InitializeSAGAButton_2.Enable = 'on';
                app.DisconnectSAGAButton_2.Enable = 'off';
                app.StartQuadrantwithCSTsButton.Enable = 'off';
                app.StartQuadrantwithMUsButton.Enable = 'off';
                app.StartRasterPlotwithMUsButton.Enable = 'off';
                app.StartDRofalltheMUsButton.Enable = 'off';
                app.DisplaythePulsetrainsButton.Enable = 'on';

                rethrow(e)
            end
        end

        % Button pushed function: StartDRofalltheMUsButton
        function StartDRofalltheMUsButtonPushed(app, event)
            C1 = strsplit(app.XaxisdisplayedDropDown.Value,'_');
            idxmuscle1 = str2double(C1{2});

            nwin = app.OnlineParameters.durationonline;
            nwinbuffmax = 5*str2double(app.RefreshrateDropDown.Value);
            k = 1;
            i = 1;
            [c,d] = butter(3,10/(str2double(app.FrequencyDropDown.Value)/2),'high');

            % Pre allocate empty matrices
            EMG = zeros(str2double(app.ChpergridDropDown.Value),nwin);
            EMGsave = zeros(app.RecordParameters.ngrid*str2double(app.ChpergridDropDown.Value),nwin);
            Force = NaN(1,nwin);
            Time = linspace(0,nwin/str2double(app.FrequencyDropDown.Value), floor(nwin*str2double(app.RefreshrateDropDown.Value)/str2double(app.FrequencyDropDown.Value)));
            EMGtmp{1} = zeros(str2double(app.ChpergridDropDown.Value),(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)));
            esample2{1} = zeros((str2double(app.ChpergridDropDown.Value)-sum(app.DecompParameters.EMGmask{idxmuscle1}))*app.DecompParameters.extensionfactor(idxmuscle1),app.DecompParameters.extensionfactor(idxmuscle1)-1);
            Distime{1} = zeros(nwin, size(app.DecompParameters.MUFilters{idxmuscle1},2));
            signal.Pulsetrain{1} = zeros(size(app.DecompParameters.MUFilters{idxmuscle1},2), nwin);
            noise_centroids{1} = ones((str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)),size(app.DecompParameters.MUFilters{idxmuscle1},2)) .* (app.DecompParameters.centroid{idxmuscle1}(:,1)');
            spike_centroids{1} = ones((str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)),size(app.DecompParameters.MUFilters{idxmuscle1},2)) .* (app.DecompParameters.centroid{idxmuscle1}(:,2)');
            MUfilt{1} = app.DecompParameters.MUFilters{idxmuscle1}' * app.DecompParameters.iReSIG{idxmuscle1};
            DR = NaN(nwin*str2double(app.RefreshrateDropDown.Value)/str2double(app.FrequencyDropDown.Value), size(app.DecompParameters.MUFilters{idxmuscle1},2));
            forcefb = zeros(1,nwin/(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)));
            feedback = zeros(1,nwin/(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)));

            % Set the properties of the axes for data viz         
            plot(app.UIAxes4, Time(1:10*str2double(app.RefreshrateDropDown.Value)), DR(1:10*str2double(app.RefreshrateDropDown.Value),:), Time(k), DR(k,:), 'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.56 0.62 0.85])
            plot(app.UIAxes5, 1, forcefb(k),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
            line(app.UIAxes5,[0 2], [feedback(k)*0.95 feedback(k)*0.95],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
            line(app.UIAxes5,[0 2], [feedback(k)*1.05 feedback(k)*1.05],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
            app.UIAxes4.XLim = [Time(1) Time(10*str2double(app.RefreshrateDropDown.Value))];
            app.UIAxes4.YLim = [0 max(app.DecompParameters.DR{idxmuscle1})*1.2];
            app.UIAxes5.XLim = [0 2];
            app.UIAxes5.YLim = [0 max(app.OnlineParameters.targetonline)*1.25];
            app.UIAxes4.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes4.YColor = [0.9412 0.9412 0.9412];
            app.UIAxes5.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes5.YColor = [0.9412 0.9412 0.9412];
            xlabel(app.UIAxes4,'Time');
            ylabel(app.UIAxes4,'Discharge rate');

            % Code within the try-catch to ensure that all devices are stopped and
            % closed properly in case of a failure.
            try

                app.ComParameters.device.start();
                while i <= nwin - str2double(app.FrequencyDropDown.Value)*5
                    [samples, num_sets] = app.ComParameters.device.sample();
                    if num_sets > 0
                        EMG(:,i:i+num_sets-1) = samples(app.ComParameters.gridchannels((idxmuscle1-1)*str2double(app.ChpergridDropDown.Value)+1:idxmuscle1*str2double(app.ChpergridDropDown.Value)), :);
                        EMGsave(:,i:i+num_sets-1) = samples(app.ComParameters.gridchannels, :);
                        Force(:,i:i+num_sets-1) = samples(app.ComParameters.gridchannels(end)+4+str2double(app.ForcechDropDown.Value),:) - app.RecordParameters.offsetforce;

                        if k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)) <= i+num_sets-1
                            forcefb(k) = mean(abs(Force((k-1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)))));
                            feedback(k) = mean(app.OnlineParameters.targetonline((k-1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))));

                            EMGtmp{1} = filtfilt(c,d,EMG(app.DecompParameters.EMGmask{idxmuscle1}==0, (k-1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)))');

                            [signal.Pulsetrain{1}(:,(k-1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))), Distime{1}((k-1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)), :), esample2{1}] = getspikesonline(EMGtmp{1}', app.DecompParameters.extensionfactor(idxmuscle1), esample2{1}, MUfilt{1}, app.DecompParameters.norm{idxmuscle1}, noise_centroids{1}, spike_centroids{1}, (str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)), str2double(app.FrequencyDropDown.Value));

                            if k < nwinbuffmax
                                DR(k,:) = sum(Distime{1}(1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)), :) > 0, 1);
                                plot(app.UIAxes4, Time(1:10*str2double(app.RefreshrateDropDown.Value)), DR(1:10*str2double(app.RefreshrateDropDown.Value),:), Time(k), DR(k,:),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.56 0.62 0.85])
                                app.UIAxes4.XLim = [Time(1) Time(10*str2double(app.RefreshrateDropDown.Value))];
                                plot(app.UIAxes5, 1, forcefb(k)/app.RecordParameters.MVC * 100,'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
                                line(app.UIAxes5,[0 2], [feedback(k)*0.95 feedback(k)*0.95],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                                line(app.UIAxes5,[0 2], [feedback(k)*1.05 feedback(k)*1.05],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                            else
                                DR(k,:) = sum(Distime{1}((k-str2double(app.RefreshrateDropDown.Value)+1)*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value))+1:k*(str2double(app.FrequencyDropDown.Value)/str2double(app.RefreshrateDropDown.Value)), :) > 0, 1);
                                DR(k,:) = mean(DR(k-(app.SmoothingEditField.Value-1):k,:), 1);
                                plot(app.UIAxes4, Time(k-5*str2double(app.RefreshrateDropDown.Value)+1:k+5*str2double(app.RefreshrateDropDown.Value)), DR(k-5*str2double(app.RefreshrateDropDown.Value)+1:k+5*str2double(app.RefreshrateDropDown.Value),:), Time(k), DR(k,:),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.56 0.62 0.85])
                                app.UIAxes4.XLim = [Time(k-5*str2double(app.RefreshrateDropDown.Value)+1) Time(k+5*str2double(app.RefreshrateDropDown.Value))];
                                plot(app.UIAxes5, 1, forcefb(k)/app.RecordParameters.MVC * 100,'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
                                line(app.UIAxes5,[0 2], [feedback(k)*0.95 feedback(k)*0.95],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                                line(app.UIAxes5,[0 2], [feedback(k)*1.05 feedback(k)*1.05],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                            end
                            app.UIAxes5.YLim = [0 max(app.OnlineParameters.targetonline)*1.25];
                            app.UIAxes4.YLim = [0 max(app.DecompParameters.DR{idxmuscle1})*1.2];

                            drawnow limitrate
                            k = k+1;
                        end
                        i = i + num_sets;
                    end
                end
                % Stop sampling on the device
                app.ComParameters.device.stop();

                % Update device configuration
                app.ComParameters.device.setDeviceConfig(app.ComParameters.deviceConfig);

                % Save the data
                signal.data = EMGsave;
                signal.target = app.OnlineParameters.targetonline;
                Force = movmean(Force,str2double(app.RefreshrateDropDown.Value));
                signal.path = Force;
                signal.fsamp = str2double(app.FrequencyDropDown.Value);
                signal.muscle = app.RecordParameters.Muscles{idxmuscle1};
                signal.nChan = size(EMGsave,1);
                signal.ngrid = app.RecordParameters.ngrid;
                signal.EMGmask{1} = app.DecompParameters.EMGmask{idxmuscle1};
                if str2double(app.ChpergridDropDown.Value) == 32
                    signal.gridname{i} = 'GR08.75MM0408';
                else
                    signal.gridname{i} = 'GR08.75MM0808';
                end
                signal.feedback{1} = 'Smooth_DR';
                for k = 1:size(Distime{1},2)
                    signal.Dischargetimes{k} = find(Distime{1}(:,k) > 0);
                end
                save([app.EditField_saving.Value 'Online ' replace(datestr(datetime('now')),':','_') '.mat'], 'signal');

                % Update the results of the last decomposition
                app.OnlineParameters.Timeplot = linspace(0,size(signal.Pulsetrain{1},2)/str2double(app.FrequencyDropDown.Value),size(signal.Pulsetrain{1},2));
                app.OnlineParameters.Pulsetrain = [];
                app.OnlineParameters.Dischargetimes = {};
                nMU = 1;
                for i = 1:length(Distime)
                    app.OnlineParameters.Pulsetrain = [app.OnlineParameters.Pulsetrain; signal.Pulsetrain{i}];
                    for j = 1:size(Distime{i},2)
                        app.OnlineParameters.Dischargetimes{nMU} =  signal.Dischargetimes{i,j};
                        nMU = nMU + 1;
                    end
                end
                clearvars Distime

                app.DisplaythePulsetrainsButton.Enable = 'on';

                % % Updates the parameters of the decomposition
                % EMG = EMG(:,str2double(app.FrequencyDropDown.Value):end);
                % [~, Distime, ~] = getPulseT(EMG, app.RecordParameters.DiscardChannels{idxmuscle1}, app.DecompParameters.MUFilters{idxmuscle1}, str2double(app.FrequencyDropDown.Value));
                % app.DecompParameters.MUFilters{idxmuscle1} = getMUfilters(EMG, app.RecordParameters.DiscardChannels{idxmuscle1}, Distime);
                % [app.DecompParameters.extensionfactor(idxmuscle1), app.DecompParameters.iReSIG{idxmuscle1}, app.DecompParameters.norm{idxmuscle1}, app.DecompParameters.centroid{idxmuscle1}] = getonlineparameters(EMG, app.RecordParameters.DiscardChannels{idxmuscle1}, app.DecompParameters.MUFilters{idxmuscle1}, str2double(app.FrequencyDropDown.Value));

            catch
                % In case of an error close all still active devices and clean up
                % library itself
                app.ComParameters.lib.cleanUp();

                % Update the UI for the next acquisition
                app.InitializeSAGAButton_2.Enable = 'on';
                app.DisconnectSAGAButton_2.Enable = 'off';
                app.StartQuadrantwithCSTsButton.Enable = 'off';
                app.StartQuadrantwithMUsButton.Enable = 'off';
                app.StartRasterPlotwithMUsButton.Enable = 'off';
                app.StartDRofalltheMUsButton.Enable = 'off';
                app.DisplaythePulsetrainsButton.Enable = 'on';
            end

        end

        % Button pushed function: DisplaythePulsetrainsButton
        function DisplaythePulsetrainsButtonPushed(app, event)
            for i = 1:size(app.OnlineParameters.Pulsetrain,1)
                plot(app.UIAxes4, app.OnlineParameters.Timeplot, app.OnlineParameters.Pulsetrain(i,:)+i, app.OnlineParameters.Timeplot(app.OnlineParameters.Dischargetimes{i}), app.OnlineParameters.Pulsetrain(i,app.OnlineParameters.Dischargetimes{i})+i,'o','LineWidth', 1, 'MarkerSize', 10, 'Color', [0.38 0.78 0.75], 'MarkerEdgeColor', [0.96 0.96 0.96]);
                hold(app.UIAxes4, 'on')
            end
            hold(app.UIAxes4, 'off')
            xlabel(app.UIAxes4,'Time');
            ylabel(app.UIAxes4,'MU');
            app.UIAxes4.XLim = [app.OnlineParameters.Timeplot(1) app.OnlineParameters.Timeplot(end)];
            app.UIAxes4.YLim = [0 i+2];
        end

        % Button pushed function: InitializeSAGAButton_2
        function InitializeSAGAButton_2Pushed(app, event)
            % If the script has been terminated manually, it can occur that the device
            % keeps sampling. To be sure, a lib.cleanUp() is performed prior to running
            % the example.
            if exist('lib', 'var')
                disp('[N/A] Ensure library is cleaned...')
                lib.cleanUp();
            end

            if app.ComParameters.fsamp == 4096
                Dividers = 0;
            elseif app.ComParameters.fsamp == 2048
                Dividers = 1;
            elseif app.ComParameters.fsamp == 1024
                Dividers = 2;
            else
                Dividers = 3;
            end

            % Initialize the library
            app.ComParameters.lib = TMSiSAGA.Library();

            % Specify desired device configuration
            app.ComParameters.deviceConfig = struct('ImpedanceMode', false,...
                'ReferenceMethod', 'common', ...
                'BaseSampleRate', 4096, ...
                'Dividers', {{'uni', Dividers; 'bip', Dividers; 'aux', Dividers;}});

            % Specify desired channel configuration
            app.ComParameters.channelConfig=struct('uni', [1:str2double(app.ChpergridDropDown.Value)*app.RecordParameters.ngrid], 'bip', [1:4], 'aux', [1:3], 'acc',0, 'dig', 0);

            % Get a single device from the connected devices
            % possible interfaces 'usb'/'network' and 'electrical'/'optical/'wifi'
            try
                % Get the device
                app.ComParameters.device = app.ComParameters.lib.getFirstAvailableDevice('usb', app.DockcomDropDown.Value);
            catch
                % Get the device
                app.ComParameters.device = app.ComParameters.lib.getFirstAvailableDevice('usb', 'electrical');

                % Open a connection to the device
                app.ComParameters.device.connect();
    
                % Change interface with docking station
                app.ComParameters.device.changeDataRecorderInterfaceTo(app.DockcomDropDown.Value)

                % Get the device with selected docking interface
                app.ComParameters.device = app.ComParameters.lib.getFirstAvailableDevice('usb', app.DockcomDropDown.Value);
            end

            % Open a connection to the device
            app.ComParameters.device.connect();

            % Set device configuration
            app.ComParameters.device.setDeviceConfig(app.ComParameters.deviceConfig);

            % Set channel configuration
            app.ComParameters.device.setChannelConfig(app.ComParameters.channelConfig);

            % Update the UI for the next acquisition
            app.GriddisplayedDropDown.Enable = 'on';
            app.InitializeSAGAButton.Enable = 'off';
            app.InitializeSAGAButton_2.Enable = 'off';
            app.DisconnectSAGAButton.Enable = 'on';
            app.DisconnectSAGAButton_2.Enable = 'on';
            app.StartVisualizationCheckEMGButton.Enable = 'on';
            app.StartVisualizationChecknoiseButton.Enable = 'on';
            app.StartButton.Enable = 'on';
            app.StartMVCButton.Enable = 'on';
            app.OffsetCheckBox.Enable = 'on';
            app.XaxisdisplayedDropDown.Enable = 'on';
            app.YaxisdisplayedDropDown.Enable = 'on';
            app.StartQuadrantwithCSTsButton.Enable = 'on';
            app.StartQuadrantwithMUsButton.Enable = 'on';
            app.StartRasterPlotwithMUsButton.Enable = 'on';
            app.StartDRofalltheMUsButton.Enable = 'on';
        end

        % Button pushed function: DisconnectSAGAButton_2
        function DisconnectSAGAButton_2Pushed(app, event)
            % Stop sampling on the device
            app.ComParameters.device.stop();

            % Disconnect from device
            app.ComParameters.device.disconnect();

            % clean up and unload the library
            app.ComParameters.lib.cleanUp();

            app.InitializeSAGAButton.Enable = 'on';
            app.InitializeSAGAButton_2.Enable = 'on';
            app.DisconnectSAGAButton.Enable = 'off';
            app.DisconnectSAGAButton_2.Enable = 'off';
            app.StartVisualizationCheckEMGButton.Enable = 'off';
            app.StartVisualizationChecknoiseButton.Enable = 'off';
            app.StartButton.Enable = 'off';
            app.StartMVCButton.Enable = 'off';
            app.OffsetCheckBox.Enable = 'off';
            app.StartQuadrantwithCSTsButton.Enable = 'off';
            app.StartQuadrantwithMUsButton.Enable = 'off';
            app.StartRasterPlotwithMUsButton.Enable = 'off';
            app.StartDRofalltheMUsButton.Enable = 'off';
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            app.UIFigure.Visible = 'off'; 
            [app.filename2,app.pathname2] = uigetfile;
            app.UIFigure.Visible = 'on'; 
            app.EditField_saving_2.Value = app.filename2;
        end

        % Button pushed function: ImportdataButton
        function ImportdataButtonPushed(app, event)
            app.MUdisplayedDropDown.Items = {};

            % Load the file
            if contains(app.filename2,'edited')
                files = load([app.pathname2 app.filename2], 'signal', 'edition'); 
                for i = 1:files.signal.ngrid
                    % Update the list and load the edited files
                    for j = 1:size(files.edition.Pulsetrain{i},1)
                        app.MUdisplayedDropDown.Items = horzcat(app.MUdisplayedDropDown.Items, {['Grid_', num2str(i), '_MU_' , num2str(j)]});
                        files.edition.silval{i,j} = getsil(files.signal.Pulsetrain{i}(j,:), files.signal.fsamp);
                    end
                end
                app.MUdisplayedDropDown.Enable = 'on';
            else
                files = load([app.pathname2 app.filename2], 'signal');   
                for i = 1:files.signal.ngrid
                    % Update the list and load the edited files
                    files.edition.Pulsetrain{i} = files.signal.Pulsetrain{i};
                    for j = 1:size(files.signal.Pulsetrain{i},1)
                        files.edition.Dischargetimes{i,j} = files.signal.Dischargetimes{i,j};
                        app.MUdisplayedDropDown.Items = horzcat(app.MUdisplayedDropDown.Items, {['Grid_', num2str(i), '_MU_' , num2str(j)]});
                        files.edition.silval{i,j} = getsil(files.signal.Pulsetrain{i}(j,:), files.signal.fsamp);
                    end
                end
                app.MUdisplayedDropDown.Enable = 'on';
                files.edition.time = linspace(0,size(files.signal.Pulsetrain{i},2)/files.signal.fsamp, size(files.signal.Pulsetrain{i},2));
            end
            
            files.parameters.nbelectrodes = str2double(app.ChpergridDropDown.Value);

            % Display the first MU
            C = strsplit(app.MUdisplayedDropDown.Value,'_');
            plot(app.UIAxesSpiketrain, files.edition.time, files.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
            hold(app.UIAxesSpiketrain, 'on')
            plot(app.UIAxesSpiketrain, files.edition.time, files.signal.target/max(files.signal.target), '--', 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
            plot(app.UIAxesSpiketrain, files.edition.time(files.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), files.edition.Pulsetrain{str2double(C{2})}(1,files.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
            hold(app.UIAxesSpiketrain, 'off')
            app.UIAxesSpiketrain.XColor = [0.9412 0.9412 0.9412];
            app.UIAxesSpiketrain.YColor = [0.9412 0.9412 0.9412];

            distime = zeros(1,length(files.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
            for i = 1:length(files.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                distime(i) = (round((files.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - files.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + files.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / files.signal.fsamp;
            end
            DR = 1./((diff(files.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/files.signal.fsamp);

            plot(app.UIAxesDR, distime, DR, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);

            app.EditField_5.Value = ['SIL = ' num2str(files.edition.silval{1,1})];

            app.UIAxesSpiketrain.XLim = [files.edition.time(1) files.edition.time(end)];
            app.UIAxesDR.XLim = [files.edition.time(1) files.edition.time(end)];
            app.UIAxesDR.YLim = [0 max(DR)*1.5];

            app.UIAxesSpiketrain.YLim = [-0.05 1.5];
            app.UIAxesDR.XColor = [0.9412 0.9412 0.9412];
            app.UIAxesDR.YColor = [0.9412 0.9412 0.9412];
            app.graphstart = files.edition.time(1);
            app.graphend = files.edition.time(end);

            app.MUedition = files;
            app.ImportdataButton.BackgroundColor = [0.5 0.5 0.5];
        end

        % Value changed function: MUdisplayedDropDown
        function MUdisplayedDropDownValueChanged(app, event)
            C = strsplit(app.MUdisplayedDropDown.Value,'_');
            plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
            hold(app.UIAxesSpiketrain, 'on')
            plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);

            plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
            hold(app.UIAxesSpiketrain, 'off')

            distime = zeros(1,length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
            for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                distime(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
            end
            DR = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);

            plot(app.UIAxesDR, distime, DR, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);

            app.EditField_5.Value = ['SIL = ' num2str(app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})})];

            app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
            app.UIAxesDR.XLim = [app.graphstart app.graphend];
            app.UIAxesSpiketrain.YLim = [-0.05 1.5];
            if ~isempty(DR)
                app.UIAxesDR.YLim = [0 max(DR)*1.5];
            else
                app.UIAxesDR.YLim = [0 1];
            end
        end

        % Button pushed function: ZoominButton
        function ZoominButtonPushed(app, event)
            duration = app.graphend - app.graphstart;
            center = app.graphstart + duration/2;
            duration = duration * 0.8;
            app.graphstart = center - duration/2;
            app.graphend = center + duration/2;
            app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
            app.UIAxesDR.XLim = [app.graphstart app.graphend];
            app.UIAxesSpiketrain.YLim = [-0.05 1.5];
        end

        % Button pushed function: ZoomoutButton
        function ZoomoutButtonPushed(app, event)
            duration = app.graphend - app.graphstart;
            center = app.graphstart + duration/2;
            duration = duration * 1.5;
            if duration > (app.MUedition.edition.time(end) - app.MUedition.edition.time(1))
                app.graphstart = app.MUedition.edition.time(1);
                app.graphend = app.MUedition.edition.time(end);
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.XLim = [app.graphstart app.graphend];
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
            else
                app.graphstart = center - duration/2;
                app.graphend = center + duration/2;
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.XLim = [app.graphstart app.graphend];
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
            end
        end

        % Button pushed function: ScrollrightButton
        function ScrollrightButtonPushed(app, event)
            duration = app.graphend - app.graphstart;
            step = 0.05 * duration;
            if (app.graphend + step) > app.MUedition.edition.time(end)
                app.graphend = app.MUedition.edition.time(end);
                app.graphstart = app.graphend - duration;
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.XLim = [app.graphstart app.graphend];
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
            else
                app.graphend = app.graphend + step;
                app.graphstart = app.graphend - duration;
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.XLim = [app.graphstart app.graphend];
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
            end
        end

        % Button pushed function: ScrollleftButton
        function ScrollleftButtonPushed(app, event)
            duration = app.graphend - app.graphstart;
            step = 0.05 * duration;
            if (app.graphstart - step) < 0
                app.graphstart = 0;
                app.graphend = app.graphstart + duration;
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.XLim = [app.graphstart app.graphend];
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
            else
                app.graphstart = app.graphstart - step;
                app.graphend = app.graphstart + duration;
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.XLim = [app.graphstart app.graphend];
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
            end
        end

        % Button pushed function: AddspikesButton
        function AddspikesButtonPushed(app, event)
            C = strsplit(app.MUdisplayedDropDown.Value,'_');
            app.roi = drawrectangle(app.UIAxesSpiketrain);
            y = [app.roi.Position(2) app.roi.Position(2) + app.roi.Position(4)];
            x = [app.roi.Position(1) app.roi.Position(1) + app.roi.Position(3)];

            temp = app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:);
            temp(x(1)> app.MUedition.edition.time | app.MUedition.edition.time>x(2)) = 0;
            [~, locs] = findpeaks(temp,'MinPeakHeight', y(1), 'MinPeakDistance', round(0.005*app.MUedition.signal.fsamp));
            delete(app.roi)

            if ~isempty(locs)
                app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})} = [app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})} locs];
                app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})} = unique(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})});
                app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})} = sort(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})});
                
                % Update graph
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
                hold(app.UIAxesSpiketrain, 'on')
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
                hold(app.UIAxesSpiketrain, 'off')
    
                distime1 = zeros(1,length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
                for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                    distime1(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
                end
                DR1 = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);
    
                plot(app.UIAxesDR, distime1, DR1, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);
    
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
                app.UIAxesDR.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.YLim = [0 max(DR1)*1.5];
            end

        end

        % Button pushed function: DeletespikesButton
        function DeletespikesButtonPushed(app, event)
            C = strsplit(app.MUdisplayedDropDown.Value,'_');
            app.roi = drawrectangle(app.UIAxesSpiketrain);
            y = [app.roi.Position(2) app.roi.Position(2) + app.roi.Position(4)];
            x = [app.roi.Position(1) app.roi.Position(1) + app.roi.Position(3)];

            idx = find(x(1)< app.MUedition.edition.time & app.MUedition.edition.time<x(2));
            idx2 = find(app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:) > y(1) & app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:) < y(2));
            idx3 = intersect(idx,idx2);
            delete(app.roi)

            if ~isempty(idx3)
                idxdel = intersect(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})},idx3);
                for j = 1:length(idxdel)
                    app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})} == idxdel(j)) = [];
                end
                % Update graph
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
                hold(app.UIAxesSpiketrain, 'on')
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
                hold(app.UIAxesSpiketrain, 'off')
    
                distime1 = zeros(1,length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
                for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                    distime1(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
                end
                DR1 = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);
    
                plot(app.UIAxesDR, distime1, DR1, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);
    
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
                app.UIAxesDR.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.YLim = [0 max(DR1)*1.5];
            end
        end

        % Button pushed function: DeleteDRButton
        function DeleteDRButtonPushed(app, event)
            C = strsplit(app.MUdisplayedDropDown.Value,'_');
            distime = zeros(1,length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
            for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                distime(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
            end
            DR = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);
            
            app.roi = drawrectangle(app.UIAxesDR);
            y = [app.roi.Position(2) app.roi.Position(2) + app.roi.Position(4)];
            x = [app.roi.Position(1) app.roi.Position(1) + app.roi.Position(3)];

            idx = find(x(1)<distime & distime<x(2));
            idx2 = find(DR > y(1));
            idx3 = intersect(idx,idx2);
            delete(app.roi)

            idxdel = zeros(1,length(idx3));
            for i = 1:length(idx3)
                if app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(idx3(i))) < app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}), app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(idx3(i)+1))
                    idxdel(i) = idx3(i);
                else
                    idxdel(i) = idx3(i)+1;
                end
            end
            if ~isempty(idx3)
                app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(idxdel) = [];
                
                % Update graph
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
                hold(app.UIAxesSpiketrain, 'on')
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
                hold(app.UIAxesSpiketrain, 'off')
    
                distime1 = zeros(1,length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
                for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                    distime1(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
                end
                DR1 = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);
    
                plot(app.UIAxesDR, distime1, DR1, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);
    
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.XLim = [app.graphstart app.graphend];
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
                app.UIAxesDR.YLim = [0 max(DR1)*1.5];
            end
        end

        % Button pushed function: FlagMUfordeletionButton
        function FlagMUfordeletionButtonPushed(app, event)
            C = strsplit(app.MUdisplayedDropDown.Value,'_');
            app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}), :) = 0;
            app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})} = [1 app.MUedition.signal.fsamp];

            plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
            hold(app.UIAxesSpiketrain, 'on')
            plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
            plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
            hold(app.UIAxesSpiketrain, 'off')

            distime1 = zeros(1,length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
            for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                distime1(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
            end
            DR1 = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);

            plot(app.UIAxesDR, distime1, DR1, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);

            app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
            app.UIAxesDR.XLim = [app.graphstart app.graphend];
            app.UIAxesSpiketrain.YLim = [-0.05 1.5];
            app.UIAxesDR.YLim = [0 max(DR1)*1.5];
        end

        % Button pushed function: RemoveoutliersButton
        function RemoveoutliersButtonPushed(app, event)
            C = strsplit(app.MUdisplayedDropDown.Value,'_');
            distime = zeros(1,length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
            for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                distime(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
            end
            DR = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);
            thres = mean(DR) + 3*std(DR);
            idx = find(DR > thres);

            idxdel = zeros(1,length(idx));
            for i = 1:length(idx)
                if app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(idx(i))) < app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}), app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(idx(i)+1))
                    idxdel(i) = idx(i);
                else
                    idxdel(i) = idx(i)+1;
                end
            end
            if ~isempty(idx)
                app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(idxdel) = [];
                
                % Update graph
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
                hold(app.UIAxesSpiketrain, 'on')
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
                hold(app.UIAxesSpiketrain, 'off')
    
                distime1 = zeros(1,length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
                for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                    distime1(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
                end
                DR1 = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);
    
                plot(app.UIAxesDR, distime1, DR1, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);
    
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.XLim = [app.graphstart app.graphend];
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
                app.UIAxesDR.YLim = [0 max(DR1)*1.5];
            end
        end

        % Button pushed function: ReevaluatewindowButton
        function ReevaluatewindowButtonPushed(app, event)
            C = strsplit(app.MUdisplayedDropDown.Value,'_');
            
            % Update MU filter
            nbextchan = 1500;
            idx = find(app.MUedition.edition.time > app.graphstart & app.MUedition.edition.time < app.graphend);
            EMG = app.MUedition.signal.data((str2double(C{2})-1)*app.MUedition.parameters.nbelectrodes+1:(str2double(C{2})-1)*app.MUedition.parameters.nbelectrodes+length(app.MUedition.signal.EMGmask{str2double(C{2})}),:);
            [c,d] = butter(3,10/(str2double(app.FrequencyDropDown.Value)/2),'high');
            EMG = filtfilt(c,d,EMG');
            EMG = EMG(idx,app.MUedition.signal.EMGmask{str2double(C{2})}==0)';
            spikes1 = intersect(idx(round(0.1*app.MUedition.signal.fsamp):end-round(0.1*app.MUedition.signal.fsamp)),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})});
            spikes2 = (spikes1 - idx(1));
            exFactor1 = round(nbextchan/size(EMG,1));
            eSIG = extend(EMG,exFactor1);
            ReSIG = eSIG*eSIG'/length(eSIG);
            iReSIGt = pinv(ReSIG);

            k = 1;
            MUFilters = sum(eSIG(:,spikes2),2);

            Pt = (MUFilters'*iReSIGt)*eSIG; % Update the pulse train
            Pt= Pt(1:size(EMG,2));
            Pt([1:round(0.1*app.MUedition.signal.fsamp) end-round(0.1*app.MUedition.signal.fsamp):end]) = 0; % Remove the edges
            Pt = Pt .* abs(Pt); % Normalized and update the pulse train
            [~,spikes] = findpeaks(Pt,'MinPeakDistance', round(app.MUedition.signal.fsamp*0.005)); % Peak detection
            Pt = Pt/mean(maxk(Pt(spikes),10));

            [L,C2] = kmeans(Pt(spikes)',2); % Kmean classification
            [~, idx2] = max(C2); % Find the class with the highest centroid
            spikes2 = spikes(L==idx2);
            ISI = diff(spikes2)/app.MUedition.signal.fsamp; % Interspike interval
            CoV = std(ISI)/mean(ISI); % Coefficient of variation
            CoVlast = CoV + 0.1;

            spikes2(Pt(spikes2)>mean(Pt(spikes2))+3*std(Pt(spikes2))) = []; % remove the outliers of the pulse train for the calculation of the filter

            % Minimization of the CoV of discharge times (end when CoV is
            % minimized)
            MUFilters = sum(eSIG(:,spikes2),2); % update W by summing the spikes    
    
            while CoV < CoVlast
                CoVlast = CoV; % save the last CoV
                spikest = spikes2; % save the last spikes
                MUFilterst = MUFilters; % save the last MU filter

                Pt = (MUFilters' * iReSIGt) * eSIG; % Update the pulse train
                Pt= Pt(1:size(EMG,2)); 
                Pt([1:round(0.1*app.MUedition.signal.fsamp) end-round(0.1*app.MUedition.signal.fsamp):end]) = 0; % Remove the edges
                Pt = Pt .* abs(Pt); % Normalized and update the pulse train
                [~,spikes] = findpeaks(Pt,'MinPeakDistance', round(app.MUedition.signal.fsamp*0.005)); % Peak detection
                Pt = Pt/mean(maxk(Pt(spikes),10));
                
                [L,C2] = kmeans(Pt(spikes)',2); % Kmean classification
                [~, idx2] = max(C2); % Find the class with the highest centroid
                spikes2 = spikes(L==idx2);
                ISI = diff(spikes2)/app.MUedition.signal.fsamp; % Interspike interval
                CoV = std(ISI)/mean(ISI); % Update CoV
                k = k + 1;
               
                spikesf = spikes2; 
                spikesf(Pt(spikesf)>mean(Pt(spikesf))+3*std(Pt(spikesf))) = []; % remove the outliers of the pulse train for the calculation of the filter
                MUFilters = sum(eSIG(:,spikesf),2);
            end
             
             % update detection of spikes
            Pt = (MUFilterst' * iReSIGt) * eSIG;
            Pt= Pt(1:size(EMG,2));
            Pt([1:round(0.1*app.MUedition.signal.fsamp) end-round(0.1*app.MUedition.signal.fsamp):end]) = 0; % Remove the edges
            Pt = Pt .* abs(Pt); % Normalized and update the pulse train
            Pt = Pt/mean(maxk(Pt(spikest),10));

            app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),idx(1)+round(0.1*app.MUedition.signal.fsamp)-1:idx(end)-round(0.1*app.MUedition.signal.fsamp)) = Pt(round(0.1*app.MUedition.signal.fsamp):end-round(0.1*app.MUedition.signal.fsamp));
            app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})} = setdiff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})},spikes1);
            app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})} = [app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}, spikest + idx(1) - 1];
            app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})} = sort(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})});

            app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})} = getsil(app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), app.MUedition.signal.fsamp);
            app.EditField_5.Value = ['SIL = ' num2str(app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})})];

            plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
            hold(app.UIAxesSpiketrain, 'on')
            plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
            plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
            hold(app.UIAxesSpiketrain, 'off')

            distime1 = zeros(1, length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
            for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                distime1(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
            end
            DR1 = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);

            plot(app.UIAxesDR, distime1, DR1, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);

            app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
            app.UIAxesSpiketrain.YLim = [-0.05 1.5];
            app.UIAxesDR.XLim = [app.graphstart app.graphend];
            app.UIAxesDR.YLim = [0 max(DR1)*1.5];
        end

        % Button pushed function: RemoveflaggedMUandduplicatesButton
        function RemoveflaggedMUandduplicatesButtonPushed(app, event)
            % Remove the flagged motor units
            fwb = waitbar(0, 'Checking flagged units and duplicates');
            ite = 0;
            itetot = 0;
            for f = 1:app.MUedition.signal.ngrid
                itetot = itetot + size(app.MUedition.edition.Pulsetrain{f},1);
            end

            for i = 1:app.MUedition.signal.ngrid
                app.MUedition.edition.Pulsetrainclean{i} = app.MUedition.edition.Pulsetrain{i};
                app.MUedition.edition.Distimeclean{i} = {};
                if size(app.MUedition.edition.Pulsetrain{i},1)>0
                    for j = 1:size(app.MUedition.edition.Pulsetrain{i},1)
                        app.MUedition.edition.Distimeclean{i}{j} = app.MUedition.edition.Dischargetimes{i,j};
                    end
                    for j = 1:size(app.MUedition.edition.Pulsetrain{i},1)
                        idx = size(app.MUedition.edition.Pulsetrain{i},1)+1-j;
                        if length(app.MUedition.edition.Dischargetimes{i,idx}) == 2 && mean(app.MUedition.edition.Pulsetrain{i}(idx,:)) == 0
                            app.MUedition.edition.Distimeclean{i}{idx} = [];
                            app.MUedition.edition.Pulsetrainclean{i}(idx,:) = [];
                        end
                        ite = ite + 1;
                        waitbar(ite/itetot, fwb,['Checking flagged units and duplicates for Grid#' num2str(i) ' _MU#' num2str(j)])
                    end
                    app.MUedition.edition.Distimeclean{i} = app.MUedition.edition.Distimeclean{i}(~cellfun('isempty',app.MUedition.edition.Distimeclean{i}));
                    % Remove the duplicates
                    [app.MUedition.edition.Pulsetrainclean{i}, app.MUedition.edition.Distimeclean{i}] = remduplicates(app.MUedition.edition.Pulsetrainclean{i}, app.MUedition.edition.Distimeclean{i}, app.MUedition.edition.Distimeclean{i}, round(app.MUedition.signal.fsamp/40), 0.00025, 0.3, app.MUedition.signal.fsamp);
                end
            end

            app.MUedition.edition.Dischargetimes = {};
            for i = 1:app.MUedition.signal.ngrid
                app.MUedition.edition.Pulsetrain{i} = app.MUedition.edition.Pulsetrainclean{i};
                for j = 1:size(app.MUedition.edition.Pulsetrain{i},1)
                    app.MUedition.edition.Dischargetimes{i,j} = app.MUedition.edition.Distimeclean{i}{j};
                end
            end

            app.MUdisplayedDropDown.Items = {};

            for i = 1:app.MUedition.signal.ngrid
                % Update the list and load the edited files
                for j = 1:size(app.MUedition.edition.Pulsetrain{i},1)
                    app.MUdisplayedDropDown.Items = horzcat(app.MUdisplayedDropDown.Items, {['Grid_', num2str(i), '_MU_' , num2str(j)]});
                end
            end

            % Update the graph
            plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{1}(1,:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
            hold(app.UIAxesSpiketrain, 'on')
            plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
            plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{1,1}), app.MUedition.edition.Pulsetrain{1}(1,app.MUedition.edition.Dischargetimes{1,1}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
            hold(app.UIAxesSpiketrain, 'off')

            distime1 = zeros(1, length(app.MUedition.edition.Dischargetimes{1,1})-1);
            for i = 1:length(app.MUedition.edition.Dischargetimes{1,1})-1
                distime1(i) = (round((app.MUedition.edition.Dischargetimes{1,1}(i+1) - app.MUedition.edition.Dischargetimes{1,1}(i)) / 2) + app.MUedition.edition.Dischargetimes{1,1}(i)) / app.MUedition.signal.fsamp;
            end
            DR1 = 1./((diff(app.MUedition.edition.Dischargetimes{1,1}))/app.MUedition.signal.fsamp);

            app.UIAxesSpiketrain.YLim = [-0.05 1.5];
            app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
            app.UIAxesDR.XLim = [app.graphstart app.graphend];
            app.UIAxesDR.YLim = [0 max(DR1)*1.5];

            delete(fwb)
            app.RemoveflaggedMUandduplicatesButton.BackgroundColor = [0.5 0.5 0.5];
        end

        % Button pushed function: PlotMUspiketrainsButton
        function PlotMUspiketrainsButtonPushed(app, event)
            for i = 1:app.MUedition.signal.ngrid
                firings = nan(size(app.MUedition.edition.Pulsetrain{i}));
                for j = 1:size(app.MUedition.edition.Pulsetrain{i},1)
                    firings(j,app.MUedition.edition.Dischargetimes{i,j}) = j;
                end
                subplot(1,app.MUedition.signal.ngrid,i)
                plot(app.MUedition.edition.time,firings,'|','MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412])
                hold on
                plot(app.MUedition.edition.time,app.MUedition.signal.target/max(app.MUedition.signal.target)*j,'--','LineWidth',1,'Color',[0.85 0.33 0.10]);
                title(['Grid#' num2str(i) ' with ' num2str(j) ' MUs'], 'Color', [0.9412 0.9412 0.9412], 'FontName', 'Avenir Next')
                xlabel('Time (s)', 'FontName', 'Avenir Next')
                ylabel('MU#', 'FontName', 'Avenir Next')
                ylim([0 j+1])
                set(gcf,'Color', [0.15 0.15 0.15]);
                set(gcf,'units','normalized','outerposition',[0 0 1 1])
                set(gca,'Color', [0.15 0.15 0.15], 'XColor', [0.9412 0.9412 0.9412], 'YColor', [0.9412 0.9412 0.9412]);
            end
            sgtitle(['Raster plots for ' num2str(i) ' grids'], 'FontName', 'Avenir Next', 'FontSize', 25, 'Color', [0.9412 0.9412 0.9412])
        end

        % Button pushed function: PlotMUfiringratesButton
        function PlotMUfiringratesButtonPushed(app, event)
            C = strsplit(app.MUdisplayedDropDown.Value,'_');
            win = hann(app.MUedition.signal.fsamp);
            for i = 1:app.MUedition.signal.ngrid
                firings = zeros(size(app.MUedition.edition.Pulsetrain{i}));
                smoothdr = zeros(size(app.MUedition.edition.Pulsetrain{i}));
                subplot(1,app.MUedition.signal.ngrid,i)
                for j = 1:size(app.MUedition.edition.Pulsetrain{i},1)
                    firings(j,app.MUedition.edition.Dischargetimes{i,j}) = 1;
                    smoothdr(j,:) = conv(firings(j,:),win,'same');
                    if i == str2double(C{2}) && j == str2double(C{4})
                        plot(app.MUedition.edition.time,smoothdr(j,:), 'Color', [0.85 0.33 0.10], 'LineWidth', 3)
                        hold on
                    else
                        plot(app.MUedition.edition.time,smoothdr(j,:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1)
                        hold on
                    end
                end
                clearvars firings smoothdr

                title(['Grid#' num2str(i) ' with ' num2str(j) ' MUs'], 'Color', [0.9412 0.9412 0.9412], 'FontName', 'Avenir Next')
                xlabel('Time (s)', 'FontName', 'Avenir Next')
                ylabel('Smoothed discharge rates', 'FontName', 'Avenir Next')
                set(gcf,'Color', [0.15 0.15 0.15]);
                set(gcf,'units','normalized','outerposition',[0 0 1 1])
                set(gca,'Color', [0.15 0.15 0.15], 'XColor', [0.9412 0.9412 0.9412], 'YColor', [0.9412 0.9412 0.9412]);
            end
            sgtitle(['Smoothed discharge rates for ' num2str(i) ' grids'], 'FontName', 'Avenir Next', 'FontSize', 25, 'Color', [0.9412 0.9412 0.9412])
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if contains(app.filename2,'edited')
                savename = [app.pathname2 app.filename2];
            else
                savename = [app.pathname2 app.filename2 '_edited.mat'];
            end
            signal = app.MUedition.signal;
            parameters = app.MUedition.parameters;
            edition = app.MUedition.edition;
            save(savename, 'signal', 'parameters', 'edition', '-v7.3');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RecordingTab = uitab(app.TabGroup);
            app.RecordingTab.Title = 'Recording';
            app.RecordingTab.ButtonDownFcn = createCallbackFcn(app, @RecordingTabButtonDown, true);
            app.TrainingTab = uitab(app.TabGroup);
            app.TrainingTab.Title = 'Training';
            app.TrainingTab.ButtonDownFcn = createCallbackFcn(app, @TrainingTabButtonDown, true);
            app.EditionTab = uitab(app.TabGroup);
            app.EditionTab.Title = 'Edition';
            app.EditionTab.ButtonDownFcn = createCallbackFcn(app, @EditionTabButtonDown, true);
            app.BiofeedbackTab = uitab(app.TabGroup);
            app.BiofeedbackTab.Title = 'Biofeedback';
            app.BiofeedbackTab.ButtonDownFcn = createCallbackFcn(app, @BiofeedbackTabButtonDown, true);
            app.VisualizationBiofeedback = uipanel(app.UIFigure);
            app.VisualizationBiofeedback.Title = 'Biofeedback based on MUs';
            app.UIAxes4 = uiaxes(app.VisualizationBiofeedback);
            xlabel(app.UIAxes4, 'Discharge rate (pps)')
            ylabel(app.UIAxes4, 'Discharge rate (pps)')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.VisualizationBiofeedback);
            ylabel(app.UIAxes5, 'Force (%MVC)')
            app.XaxisdisplayedDropDown = uidropdown(app.VisualizationBiofeedback);
            app.XaxisdisplayedDropDown.Items = {'No MUs'};
            app.XaxisdisplayedDropDown.Enable = 'off';
            app.XaxisdisplayedDropDown.Value = 'No MUs';
            app.XaxisdisplayedDropDownLabel = uilabel(app.VisualizationBiofeedback);
            app.XaxisdisplayedDropDownLabel.Text = 'X axis displayed';
            app.DisconnectSAGAButton_2 = uibutton(app.VisualizationBiofeedback, 'push');
            app.DisconnectSAGAButton_2.ButtonPushedFcn = createCallbackFcn(app, @DisconnectSAGAButton_2Pushed, true);
            app.DisconnectSAGAButton_2.Enable = 'off';
            app.DisconnectSAGAButton_2.Text = 'Disconnect SAGA';
            app.YaxisdisplayedDropDown = uidropdown(app.VisualizationBiofeedback);
            app.YaxisdisplayedDropDown.Items = {'No MUs'};
            app.YaxisdisplayedDropDown.Enable = 'off';
            app.YaxisdisplayedDropDown.Value = 'No MUs';
            app.YaxisdisplayedDropDownLabel = uilabel(app.VisualizationBiofeedback);
            app.YaxisdisplayedDropDownLabel.Text = 'Y axis displayed';
            app.InitializeSAGAButton_2 = uibutton(app.VisualizationBiofeedback, 'push');
            app.InitializeSAGAButton_2.ButtonPushedFcn = createCallbackFcn(app, @InitializeSAGAButton_2Pushed, true);
            app.InitializeSAGAButton_2.Text = 'Initialize SAGA';
            app.ManualeditionPanel = uipanel(app.UIFigure);
            app.ManualeditionPanel.Title = 'Manual edition';
            app.UIAxesSpiketrain = uiaxes(app.ManualeditionPanel);
            xlabel(app.UIAxesSpiketrain, 'Time (s)')
            ylabel(app.UIAxesSpiketrain, 'Pulse train')
            zlabel(app.UIAxesSpiketrain, 'Z')
            app.UIAxesDR = uiaxes(app.ManualeditionPanel);
            xlabel(app.UIAxesDR, 'Time (s)')
            ylabel(app.UIAxesDR, 'Discharge rate')
            zlabel(app.UIAxesDR, 'Z')
            app.MUdisplayedDropDown = uidropdown(app.ManualeditionPanel);
            app.MUdisplayedDropDown.Items = {'No MUs'};
            app.MUdisplayedDropDown.ValueChangedFcn = createCallbackFcn(app, @MUdisplayedDropDownValueChanged, true);
            app.MUdisplayedDropDown.Enable = 'off';
            app.MUdisplayedDropDown.Value = 'No MUs';
            app.MUdisplayedDropDownLabel = uilabel(app.ManualeditionPanel);
            app.MUdisplayedDropDownLabel.Text = 'MU displayed #';
            app.RemoveoutliersButton = uibutton(app.ManualeditionPanel, 'push');
            app.RemoveoutliersButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveoutliersButtonPushed, true);
            app.RemoveoutliersButton.Text = 'Remove outliers';
            app.FlagMUfordeletionButton = uibutton(app.ManualeditionPanel, 'push');
            app.FlagMUfordeletionButton.ButtonPushedFcn = createCallbackFcn(app, @FlagMUfordeletionButtonPushed, true);
            app.FlagMUfordeletionButton.Text = 'Flag MU for deletion';
            app.AddspikesButton = uibutton(app.ManualeditionPanel, 'push');
            app.AddspikesButton.ButtonPushedFcn = createCallbackFcn(app, @AddspikesButtonPushed, true);
            app.AddspikesButton.Text = 'Add spikes';
            app.DeletespikesButton = uibutton(app.ManualeditionPanel, 'push');
            app.DeletespikesButton.ButtonPushedFcn = createCallbackFcn(app, @DeletespikesButtonPushed, true);
            app.DeletespikesButton.Text = 'Delete spikes';
            app.DeleteDRButton = uibutton(app.ManualeditionPanel, 'push');
            app.DeleteDRButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteDRButtonPushed, true);
            app.DeleteDRButton.Text = 'Delete DR';
            app.ScrollleftButton = uibutton(app.ManualeditionPanel, 'push');
            app.ScrollleftButton.ButtonPushedFcn = createCallbackFcn(app, @ScrollleftButtonPushed, true);
            app.ScrollleftButton.Text = '< Scroll left';
            app.ZoominButton = uibutton(app.ManualeditionPanel, 'push');
            app.ZoominButton.ButtonPushedFcn = createCallbackFcn(app, @ZoominButtonPushed, true);
            app.ZoominButton.Text = 'Zoom in';
            app.ZoomoutButton = uibutton(app.ManualeditionPanel, 'push');
            app.ZoomoutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomoutButtonPushed, true);
            app.ZoomoutButton.Text = 'Zoom out';
            app.ScrollrightButton = uibutton(app.ManualeditionPanel, 'push');
            app.ScrollrightButton.ButtonPushedFcn = createCallbackFcn(app, @ScrollrightButtonPushed, true);
            app.ScrollrightButton.Text = 'Scroll right >';
            app.ReevaluatewindowButton = uibutton(app.ManualeditionPanel, 'push');
            app.ReevaluatewindowButton.ButtonPushedFcn = createCallbackFcn(app, @ReevaluatewindowButtonPushed, true);
            app.ReevaluatewindowButton.Text = 'Reevaluate window';
            app.EditField_5 = uieditfield(app.ManualeditionPanel, 'text');
            app.VisualizationPanel = uipanel(app.UIFigure);
            app.VisualizationPanel.Title = 'Visualization';
            app.UIAxes3 = uiaxes(app.VisualizationPanel);
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Channels')
            zlabel(app.UIAxes3, 'Z')
            app.GriddisplayedDropDown = uidropdown(app.VisualizationPanel);
            app.GriddisplayedDropDown.Items = {'No Muscles'};
            app.GriddisplayedDropDown.Enable = 'off';
            app.GriddisplayedDropDown.Value = 'No Muscles';
            app.GriddisplayedDropDownLabel = uilabel(app.VisualizationPanel);
            app.GriddisplayedDropDownLabel.Text = 'Grid displayed #';
            app.DisconnectSAGAButton = uibutton(app.VisualizationPanel, 'push');
            app.DisconnectSAGAButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectSAGAButtonPushed, true);
            app.DisconnectSAGAButton.Enable = 'off';
            app.DisconnectSAGAButton.Text = 'Disconnect SAGA';
            app.InitializeSAGAButton = uibutton(app.VisualizationPanel, 'push');
            app.InitializeSAGAButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeSAGAButtonPushed, true);
            app.InitializeSAGAButton.Enable = 'off';
            app.InitializeSAGAButton.Text = 'Initialize SAGA';
            app.BiofeedbackSettingsPanel = uipanel(app.UIFigure);
            app.BiofeedbackSettingsPanel.Title = 'Biofeedback Settings';
            app.UIAxes2 = uiaxes(app.BiofeedbackSettingsPanel);
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Force')
            zlabel(app.UIAxes2, 'Z')
            app.MVCtargetEditFieldLabel = uilabel(app.BiofeedbackSettingsPanel);
            app.MVCtargetEditFieldLabel.Text = 'MVC target';
            app.MVCtargetEditField = uieditfield(app.BiofeedbackSettingsPanel, 'numeric');
            app.MVCtargetEditField.ValueChangedFcn = createCallbackFcn(app, @MVCtargetEditFieldValueChanged, true);
            app.DurationrampEditFieldLabel = uilabel(app.BiofeedbackSettingsPanel);
            app.DurationrampEditFieldLabel.Text = 'Duration ramp';
            app.DurationrampEditField = uieditfield(app.BiofeedbackSettingsPanel, 'numeric');
            app.DurationrampEditField.ValueChangedFcn = createCallbackFcn(app, @DurationrampEditFieldValueChanged, true);
            app.DurationplateauEditFieldLabel = uilabel(app.BiofeedbackSettingsPanel);
            app.DurationplateauEditFieldLabel.Text = 'Duration plateau';
            app.DurationplateauEditField = uieditfield(app.BiofeedbackSettingsPanel, 'numeric');
            app.DurationplateauEditField.ValueChangedFcn = createCallbackFcn(app, @DurationplateauEditFieldValueChanged, true);
            app.DurationrestEditFieldLabel = uilabel(app.BiofeedbackSettingsPanel);
            app.DurationrestEditFieldLabel.Text = 'Duration rest';
            app.DurationrestEditField = uieditfield(app.BiofeedbackSettingsPanel, 'numeric');
            app.DurationrestEditField.ValueChangedFcn = createCallbackFcn(app, @DurationrestEditFieldValueChanged, true);
            app.NbiterationsEditFieldLabel = uilabel(app.BiofeedbackSettingsPanel);
            app.NbiterationsEditFieldLabel.Text = 'Nb iterations';
            app.NbiterationsEditField = uieditfield(app.BiofeedbackSettingsPanel, 'numeric');
            app.NbiterationsEditField.ValueChangedFcn = createCallbackFcn(app, @NbiterationsEditFieldValueChanged, true);
            app.NbiterationsEditField.Value = 1;
            app.StartQuadrantwithCSTsButton = uibutton(app.BiofeedbackSettingsPanel, 'push');
            app.StartQuadrantwithCSTsButton.ButtonPushedFcn = createCallbackFcn(app, @StartQuadrantwithCSTsButtonPushed, true);
            app.StartQuadrantwithCSTsButton.Enable = 'off';
            app.StartQuadrantwithCSTsButton.Text = 'Start Quadrant with CSTs';
            app.StartQuadrantwithMUsButton = uibutton(app.BiofeedbackSettingsPanel, 'push');
            app.StartQuadrantwithMUsButton.ButtonPushedFcn = createCallbackFcn(app, @StartQuadrantwithMUsButtonPushed, true);
            app.StartQuadrantwithMUsButton.Enable = 'off';
            app.StartQuadrantwithMUsButton.Text = 'Start Quadrant with MUs';
            app.StartDRofalltheMUsButton = uibutton(app.BiofeedbackSettingsPanel, 'push');
            app.StartDRofalltheMUsButton.ButtonPushedFcn = createCallbackFcn(app, @StartDRofalltheMUsButtonPushed, true);
            app.StartDRofalltheMUsButton.Enable = 'off';
            app.StartDRofalltheMUsButton.Text = 'Start DR of all the MUs';
            app.ForceparametersLabel = uilabel(app.BiofeedbackSettingsPanel);
            app.ForceparametersLabel.Text = 'Force parameters';
            app.TypeofbiofeedbacksLabel = uilabel(app.BiofeedbackSettingsPanel);
            app.TypeofbiofeedbacksLabel.Text = 'Type of biofeedbacks';
            app.StartRasterPlotwithMUsButton = uibutton(app.BiofeedbackSettingsPanel, 'push');
            app.StartRasterPlotwithMUsButton.ButtonPushedFcn = createCallbackFcn(app, @StartRasterPlotwithMUsButtonPushed, true);
            app.StartRasterPlotwithMUsButton.Enable = 'off';
            app.StartRasterPlotwithMUsButton.Text = 'Start Raster Plot with MUs';
            app.DisplaythePulsetrainsButton = uibutton(app.BiofeedbackSettingsPanel, 'push');
            app.DisplaythePulsetrainsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaythePulsetrainsButtonPushed, true);
            app.DisplaythePulsetrainsButton.Enable = 'off';
            app.DisplaythePulsetrainsButton.Text = 'Display the Pulse trains';
            app.QualityofthedecompositionLabel = uilabel(app.BiofeedbackSettingsPanel);
            app.QualityofthedecompositionLabel.Text = 'Quality of the decomposition';
            app.ImportMUfiltersButton = uibutton(app.BiofeedbackSettingsPanel, 'push');
            app.ImportMUfiltersButton.ButtonPushedFcn = createCallbackFcn(app, @ImportMUfiltersButtonPushed, true);
            app.ImportMUfiltersButton.Text = 'Import MU filters';
            app.SmoothingEditFieldLabel = uilabel(app.BiofeedbackSettingsPanel);
            app.SmoothingEditFieldLabel.Text = 'Smoothing';
            app.SmoothingEditField = uieditfield(app.BiofeedbackSettingsPanel, 'numeric');
            app.SmoothingEditField.Value = 4;
            app.EditionPanel = uipanel(app.UIFigure);
            app.EditionPanel.Title = 'Edition';
            app.EditField_saving_2 = uieditfield(app.EditionPanel, 'text');
            app.EditField_saving_2.Editable = 'off';
            app.EditField_saving_2.Value = 'File name';
            app.SelectfileButton = uibutton(app.EditionPanel, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.ImportdataButton = uibutton(app.EditionPanel, 'push');
            app.ImportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportdataButtonPushed, true);
            app.ImportdataButton.Text = 'Import data';
            app.RemoveflaggedMUandduplicatesButton = uibutton(app.EditionPanel, 'push');
            app.RemoveflaggedMUandduplicatesButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveflaggedMUandduplicatesButtonPushed, true);
            app.RemoveflaggedMUandduplicatesButton.Text = 'Remove flagged MU and duplicates';
            app.PlotMUspiketrainsButton = uibutton(app.EditionPanel, 'push');
            app.PlotMUspiketrainsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMUspiketrainsButtonPushed, true);
            app.PlotMUspiketrainsButton.Text = 'Plot MU spike trains';
            app.SaveButton = uibutton(app.EditionPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.VisualisationLabel = uilabel(app.EditionPanel);
            app.VisualisationLabel.Text = 'Visualisation';
            app.SavetheeditionLabel = uilabel(app.EditionPanel);
            app.SavetheeditionLabel.Text = 'Save the edition';
            app.PlotMUfiringratesButton = uibutton(app.EditionPanel, 'push');
            app.PlotMUfiringratesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMUfiringratesButtonPushed, true);
            app.PlotMUfiringratesButton.Text = 'Plot MU firing rates';
            app.CleaningLabel = uilabel(app.EditionPanel);
            app.CleaningLabel.Text = 'Cleaning';
            app.TrainingSettingsPanel = uipanel(app.UIFigure);
            app.TrainingSettingsPanel.Title = 'Training Settings';
            app.UIAxes = uiaxes(app.TrainingSettingsPanel);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Force')
            zlabel(app.UIAxes, 'Z')
            app.MVCparametersLabel = uilabel(app.TrainingSettingsPanel);
            app.MVCparametersLabel.Text = 'MVC parameters';
            app.DurationEditFieldLabel = uilabel(app.TrainingSettingsPanel);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.TrainingSettingsPanel, 'numeric');
            app.RestEditFieldLabel = uilabel(app.TrainingSettingsPanel);
            app.RestEditFieldLabel.Text = 'Rest';
            app.RestEditField = uieditfield(app.TrainingSettingsPanel, 'numeric');
            app.OffsetCheckBox = uicheckbox(app.TrainingSettingsPanel);
            app.OffsetCheckBox.ValueChangedFcn = createCallbackFcn(app, @OffsetCheckBoxValueChanged, true);
            app.OffsetCheckBox.Enable = 'off';
            app.OffsetCheckBox.Text = 'Offset';
            app.EditField_offset = uieditfield(app.TrainingSettingsPanel, 'numeric');
            app.StartMVCButton = uibutton(app.TrainingSettingsPanel, 'push');
            app.StartMVCButton.ButtonPushedFcn = createCallbackFcn(app, @StartMVCButtonPushed, true);
            app.StartMVCButton.Enable = 'off';
            app.StartMVCButton.Text = 'Start MVC';
            app.Gauge = uigauge(app.TrainingSettingsPanel, 'semicircular');
            app.MVCTargetEditFieldLabel = uilabel(app.TrainingSettingsPanel);
            app.MVCTargetEditFieldLabel.Text = 'MVC Target';
            app.MVCTargetEditField = uieditfield(app.TrainingSettingsPanel, 'numeric');
            app.MVCTargetEditField.ValueChangedFcn = createCallbackFcn(app, @MVCTargetEditFieldValueChanged, true);
            app.DurationtrainingEditFieldLabel = uilabel(app.TrainingSettingsPanel);
            app.DurationtrainingEditFieldLabel.Text = 'Duration training';
            app.DurationtrainingEditField = uieditfield(app.TrainingSettingsPanel, 'numeric');
            app.DurationtrainingEditField.ValueChangedFcn = createCallbackFcn(app, @DurationtrainingEditFieldValueChanged, true);
            app.TrainingparametersLabel = uilabel(app.TrainingSettingsPanel);
            app.TrainingparametersLabel.Text = 'Training parameters';
            app.NbIterationsEditFieldLabel = uilabel(app.TrainingSettingsPanel);
            app.NbIterationsEditFieldLabel.Text = 'Nb Iterations';
            app.NbIterationsEditField = uieditfield(app.TrainingSettingsPanel, 'numeric');
            app.NbIterationsEditField.Value = 50;
            app.StartButton = uibutton(app.TrainingSettingsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.EditField_MVC = uieditfield(app.TrainingSettingsPanel, 'text');
            app.SILthresholdEditFieldLabel = uilabel(app.TrainingSettingsPanel);
            app.SILthresholdEditFieldLabel.Text = 'SIL threshold';
            app.SILthresholdEditField = uieditfield(app.TrainingSettingsPanel, 'numeric');
            app.SILthresholdEditField.Value = 0.9;
            app.COVthresholdEditFieldLabel = uilabel(app.TrainingSettingsPanel);
            app.COVthresholdEditFieldLabel.Text = 'COV threshold';
            app.COVthresholdEditField = uieditfield(app.TrainingSettingsPanel, 'numeric');
            app.COVthresholdEditField.Value = 0.5;
            app.RecordingSettingsPanel = uipanel(app.UIFigure);
            app.RecordingSettingsPanel.Title = 'Recording Settings';
            app.Grid1CheckBox = uicheckbox(app.RecordingSettingsPanel);
            app.Grid1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Grid1CheckBoxValueChanged, true);
            app.Grid1CheckBox.Text = 'Grid #1';
            app.Grid2CheckBox = uicheckbox(app.RecordingSettingsPanel);
            app.Grid2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Grid2CheckBoxValueChanged, true);
            app.Grid2CheckBox.Enable = 'off';
            app.Grid2CheckBox.Text = 'Grid #2';
            app.EditField_1 = uieditfield(app.RecordingSettingsPanel, 'text');
            app.EditField_1.ValueChangedFcn = createCallbackFcn(app, @EditField_1ValueChanged, true);
            app.EditField_1.Editable = 'off';
            app.EditField_2 = uieditfield(app.RecordingSettingsPanel, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Editable = 'off';
            app.ChannelsLabel = uilabel(app.RecordingSettingsPanel);
            app.ChannelsLabel.Text = 'Channels';
            app.MusclenamesLabel = uilabel(app.RecordingSettingsPanel);
            app.MusclenamesLabel.Text = 'Muscle names';
            app.FrequencyDropDownLabel = uilabel(app.RecordingSettingsPanel);
            app.FrequencyDropDownLabel.Text = 'Frequency';
            app.FrequencyDropDown = uidropdown(app.RecordingSettingsPanel);
            app.FrequencyDropDown.Items = {'512', '1024', '2048', '4096'};
            app.FrequencyDropDown.Value = '4096';
            app.StartVisualizationCheckEMGButton = uibutton(app.RecordingSettingsPanel, 'push');
            app.StartVisualizationCheckEMGButton.ButtonPushedFcn = createCallbackFcn(app, @StartVisualizationCheckEMGButtonPushed, true);
            app.StartVisualizationCheckEMGButton.Enable = 'off';
            app.StartVisualizationCheckEMGButton.Text = 'Start Visualization -  Check EMG';
            app.SavingsettingsLabel = uilabel(app.RecordingSettingsPanel);
            app.SavingsettingsLabel.Text = 'Saving settings';
            app.EditField_saving = uieditfield(app.RecordingSettingsPanel, 'text');
            app.EditField_saving.Editable = 'off';
            app.EditField_saving.Value = 'Folder name';
            app.SelectfolderButton = uibutton(app.RecordingSettingsPanel, 'push');
            app.SelectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfolderButtonPushed, true);
            app.SelectfolderButton.Text = 'Select folder';
            app.StartVisualizationChecknoiseButton = uibutton(app.RecordingSettingsPanel, 'push');
            app.StartVisualizationChecknoiseButton.ButtonPushedFcn = createCallbackFcn(app, @StartVisualizationChecknoiseButtonPushed, true);
            app.StartVisualizationChecknoiseButton.Enable = 'off';
            app.StartVisualizationChecknoiseButton.Text = 'Start Visualization -  Check noise';
            app.ChpergridDropDownLabel = uilabel(app.RecordingSettingsPanel);
            app.ChpergridDropDownLabel.Text = 'Ch per grid';
            app.ChpergridDropDown = uidropdown(app.RecordingSettingsPanel);
            app.ChpergridDropDown.Items = {'32', '64'};
            app.ChpergridDropDown.ValueChangedFcn = createCallbackFcn(app, @ChpergridDropDownValueChanged, true);
            app.ChpergridDropDown.Value = '32';
            app.RefreshrateDropDownLabel = uilabel(app.RecordingSettingsPanel);
            app.RefreshrateDropDownLabel.Text = 'Refresh rate';
            app.RefreshrateDropDown = uidropdown(app.RecordingSettingsPanel);
            app.RefreshrateDropDown.Items = {'2', '4', '8', '16', '32'};
            app.RefreshrateDropDown.Value = '8';
            app.DockcomDropDownLabel = uilabel(app.RecordingSettingsPanel);
            app.DockcomDropDownLabel.Text = 'Dock com';
            app.DockcomDropDown = uidropdown(app.RecordingSettingsPanel);
            app.DockcomDropDown.Items = {'electrical', 'optical', 'wifi'};
            app.DockcomDropDown.Value = 'optical';
            app.ForcechDropDownLabel = uilabel(app.RecordingSettingsPanel);
            app.ForcechDropDownLabel.Text = 'Force ch';
            app.ForcechDropDown = uidropdown(app.RecordingSettingsPanel);
            app.ForcechDropDown.Items = {'1', '2', '3'};
            app.ForcechDropDown.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ISpin_SAGA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end